classdef ICPro < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ICProUIFigure                 matlab.ui.Figure
        ICProMenu                     matlab.ui.container.Menu
        AboutICProMenu                matlab.ui.container.Menu
        CheckforUpdatesMenu           matlab.ui.container.Menu
        PreferencesMenu               matlab.ui.container.Menu
        GuideMenu                     matlab.ui.container.Menu
        ExportMenu                    matlab.ui.container.Menu
        IndResExp                     matlab.ui.container.Menu
        TEIndRes                      matlab.ui.container.Menu
        IDLRplots                     matlab.ui.container.Menu
        IDexport                      matlab.ui.container.Menu
        DCexport                      matlab.ui.container.Menu
        BCexport                      matlab.ui.container.Menu
        OCexport                      matlab.ui.container.Menu
        SCexport                      matlab.ui.container.Menu
        QCexport                      matlab.ui.container.Menu
        RCexport                      matlab.ui.container.Menu
        Rawexport                     matlab.ui.container.Menu
        ThUIndRes                     matlab.ui.container.Menu
        ThUIDExp                      matlab.ui.container.Menu
        SamRatioExp                   matlab.ui.container.Menu
        NatURatioExp                  matlab.ui.container.Menu
        TEsheet                       matlab.ui.container.Menu
        ThUsheet                      matlab.ui.container.Menu
        BatchMenu                     matlab.ui.container.Menu
        SaveBatchMenu                 matlab.ui.container.Menu
        SaveAsBatchMenu               matlab.ui.container.Menu
        LoadBatchMenu                 matlab.ui.container.Menu
        DeleteBatchMenu               matlab.ui.container.Menu
        ClearBatchMenu                matlab.ui.container.Menu
        Panel_ThUResults              matlab.ui.container.Panel
        TabGroup_ThUResults           matlab.ui.container.TabGroup
        Tab_ConcRatio                 matlab.ui.container.Tab
        Table_ThUresults              matlab.ui.control.Table
        ThUBCcheck                    matlab.ui.control.CheckBox
        CUSelThU                      matlab.ui.container.ButtonGroup
        ErrpThU                       matlab.ui.control.RadioButton
        ErrThU                        matlab.ui.control.RadioButton
        ConcThU                       matlab.ui.control.RadioButton
        DataSelThU                    matlab.ui.container.ButtonGroup
        ThUIDButton                   matlab.ui.control.ToggleButton
        RatioNButton                  matlab.ui.control.ToggleButton
        RatioSButton                  matlab.ui.control.ToggleButton
        Tab_SpikeRecov                matlab.ui.container.Tab
        RecoveryFig                   matlab.ui.control.UIAxes
        Panel_TEResults               matlab.ui.container.Panel
        TabGroup_TEResults            matlab.ui.container.TabGroup
        Tab_Concentrations            matlab.ui.container.Tab
        Table_TEresults               matlab.ui.control.Table
        DataSelTE                     matlab.ui.container.ButtonGroup
        CUSelTE                       matlab.ui.container.ButtonGroup
        ErrpTE                        matlab.ui.control.RadioButton
        ErrTE                         matlab.ui.control.RadioButton
        ConcTE                        matlab.ui.control.RadioButton
        IDButton                      matlab.ui.control.ToggleButton
        DCButton                      matlab.ui.control.ToggleButton
        BCButton                      matlab.ui.control.ToggleButton
        OCButton                      matlab.ui.control.ToggleButton
        SCButton                      matlab.ui.control.ToggleButton
        QCButton                      matlab.ui.control.ToggleButton
        RCButton                      matlab.ui.control.ToggleButton
        RawButton                     matlab.ui.control.ToggleButton
        Tab_IDvsLR                    matlab.ui.container.Tab
        ThIDFig                       matlab.ui.control.UIAxes
        UIDFig                        matlab.ui.control.UIAxes
        Button_Run                    matlab.ui.control.Button
        Panel_AnalysisInfo            matlab.ui.container.Panel
        Button_Preferences            matlab.ui.control.Button
        Button_InputSheet             matlab.ui.control.Button
        AnalysisSelection             matlab.ui.container.ButtonGroup
        Toggle_TE                     matlab.ui.control.ToggleButton
        Toggle_ThU                    matlab.ui.control.ToggleButton
        Label_BlankID                 matlab.ui.control.Label
        Label_SampleID                matlab.ui.control.Label
        Field_BlankID                 matlab.ui.control.EditField
        Field_SampleID                matlab.ui.control.EditField
        Field_RawDataPath             matlab.ui.control.EditField
        Button_RawDataPath            matlab.ui.control.Button
        Panel_Spikes                  matlab.ui.container.Panel
        Field_RY230                   matlab.ui.control.NumericEditField
        Field_RYb236                  matlab.ui.control.NumericEditField
        Field_RY236                   matlab.ui.control.NumericEditField
        IsotopeamtratioU236234Label   matlab.ui.control.Label
        IsotopeamtratioU236238Label   matlab.ui.control.Label
        ConcentrationLabel_3          matlab.ui.control.Label
        Field_CY236                   matlab.ui.control.NumericEditField
        Button_Delete236              matlab.ui.control.Button
        Button_EdtSve236              matlab.ui.control.Button
        DropDown_236                  matlab.ui.control.DropDown
        ULabel                        matlab.ui.control.Label
        Field_CY230                   matlab.ui.control.NumericEditField
        IsotopeamtratioTh230229Label  matlab.ui.control.Label
        ConcentrationLabel_2          matlab.ui.control.Label
        Button_Delete230              matlab.ui.control.Button
        Button_EdtSve230              matlab.ui.control.Button
        DropDown_230                  matlab.ui.control.DropDown
        ThLabel_2                     matlab.ui.control.Label
        Button_Delete229              matlab.ui.control.Button
        Button_EdtSve229              matlab.ui.control.Button
        Field_RY229                   matlab.ui.control.NumericEditField
        IsotopeamtratioTh229230Label  matlab.ui.control.Label
        ConcentrationLabel            matlab.ui.control.Label
        Field_CY229                   matlab.ui.control.NumericEditField
        DropDown_229                  matlab.ui.control.DropDown
        ThLabel                       matlab.ui.control.Label
        ContextMenu_CopyThUResults    matlab.ui.container.ContextMenu
        CopyThU                       matlab.ui.container.Menu
        ContextMenu_CopyTEResults     matlab.ui.container.ContextMenu
        CopyTE                        matlab.ui.container.Menu
    end


    properties (Access = public)
        Version = '3.1.2';
        AppPath = dir([mfilename('fullpath'),'.mlapp']);    % Description
        Output = struct;                                    % Processing input
        Input = struct;                                     % Processing output
        BatchName = 'unsaved batch'                         % Batch name
        Prefs = struct;
    end

    methods (Access = private)
        function IN = getInput(app)
            %---------------------------------------------------------------------------------
            % Function: Get input parameters
            % This function copies all input parameters and saves them locally as a batch
            % file.
            %% -------------------------------------------------------------------------------

            % Analysis information
            app.Input.AnalysisInfo.Toggle = app.AnalysisSelection.SelectedObject.Tag;       % Analysis toggle
            app.Input.AnalysisInfo.RawPath = app.Field_RawDataPath.Value;                   % Raw file directory
            app.Input.AnalysisInfo.SID = app.Field_SampleID.Value;                          % Sample ID
            app.Input.AnalysisInfo.pBID = app.Field_BlankID.Value;                          % Blank ID

            % Spike input
            app.Input.SpikePar.Th229RY = app.Field_RY229.Value;                             % Th229 RY
            app.Input.SpikePar.Th229CY = app.Field_CY229.Value;                             % Th229 CY
            app.Input.SpikePar.Th230RY = app.Field_RY230.Value;                             % Th230 RY
            app.Input.SpikePar.Th230CY = app.Field_CY230.Value;                             % Th230 CY
            app.Input.SpikePar.U236RY = app.Field_RY236.Value;                              % U236 RY (for 238U ID)
            app.Input.SpikePar.U236RYb = app.Field_RYb236.Value;                            % U236 RY (for 234U ID)
            app.Input.SpikePar.U236CY = app.Field_CY236.Value;                              % U236 CY
            app.Input.SpikeSel = [app.DropDown_229.Value,app.DropDown_230.Value,app.DropDown_236.Value];

            % Isotopic dilution input
            if app.Toggle_ThU.Value == 1 && isfolder(app.Input.AnalysisInfo.RawPath) && isfile(fullfile(app.Input.AnalysisInfo.RawPath,'ThU_WtInputTable.xlsx'))
                InputData = readtable(fullfile(app.Input.AnalysisInfo.RawPath,'ThU_WtInputTable.xlsx'));
                app.Input.WtPar.m_sed = InputData{:,2}';                                    % Sediment weight
                app.Input.WtPar.m_ThBC = InputData{:,3}';                                   % 229 Th spike weight
                app.Input.WtPar.m_UBC = InputData{:,4}';                                    % 236 (big cut) spike weight
            elseif app.Toggle_TE.Value == 1 && isfile(app.Input.AnalysisInfo.RawPath) && isfile(fullfile(dir(app.Input.AnalysisInfo.RawPath).folder,'TE_WtInputTable.xlsx'))
                TErawfileDir = dir(app.Input.AnalysisInfo.RawPath);
                InputData = readtable(fullfile(TErawfileDir.folder,'TE_WtInputTable.xlsx'));
                app.Input.WtPar.m_sed = InputData{:,2}';                                    % Sediment weight
                app.Input.WtPar.m_4ml = InputData{:,3}';                                    % 4ml weight
                app.Input.WtPar.m_10ml = InputData{:,4}';                                   % 10ml weight
                app.Input.WtPar.m_400ul = InputData{:,5}';                                  % 400ul weight
                app.Input.WtPar.m_ThSC = InputData{:,6}';                                   % Th230 weight
                app.Input.WtPar.m_USC = InputData{:,7}';                                    % U236 (small cut) weight
                app.Input.WtPar.m_UBC = InputData{:,8}';                                    % U236 (big cut) weight
                app.Input.cps.Sample = InputData{:,9:12}';                                  % Th and U raw counts
            end

            % Save to export variable 'IN'
            IN = app.Input;
            IN.BatchName{1} = app.BatchName;
        end

        function setInput(app,event,IN)
            %---------------------------------------------------------------------------------
            % Function: Import input parameters
            %% -------------------------------------------------------------------------------

            % Assign input to app property 'Input'
            app.Input = IN;
            clearvars('IN')

            % Set analysis information
            if strcmp(app.Input.AnalysisInfo.Toggle,'ThUtoggled')
                app.AnalysisSelection.SelectedObject = app.Toggle_ThU;                           % Analysis toggle
            elseif strcmp(app.Input.AnalysisInfo.Toggle,'TEtoggled')
                app.AnalysisSelection.SelectedObject = app.Toggle_TE;                           % Analysis toggle
            end
            app.Field_RawDataPath.Value = app.Input.AnalysisInfo.RawPath;                   % Raw file directory
            app.Field_SampleID.Value = app.Input.AnalysisInfo.SID;                          % Sample ID
            app.Field_BlankID.Value = app.Input.AnalysisInfo.pBID;                          % Blank ID

            % Set spikes and toggle analyses
            Spikes(app,event)

            AnalysisToggle(app,event)

        end

        function resetInput(app,event)
            %---------------------------------------------------------------------------------
            % Function: Reset all input fields
            % This function resets all input fields to initial conditions.
            %% -------------------------------------------------------------------------------

            app.BatchName = 'unsaved batch';

            % Reset analysis selection to Th/U (default)
            app.AnalysisSelection.SelectedObject = app.Toggle_ThU;

            % Reset analysis information
            app.Field_RawDataPath.Value = 'Raw data directory';
            app.Field_SampleID.Value = char([]);
            app.Field_BlankID.Value = char([]);

            % Reset spikes
            app.DropDown_229.Value = 0;
            app.DropDown_230.Value = 0;
            app.DropDown_236.Value = 0;

            % Reset spikes and toggle analyses
            Spikes(app,event)
            AnalysisToggle(app,event)

            % Reset results
            app.Table_ThUresults.Data = [];
            app.Table_TEresults.Data = [];
            cla(app.ThIDFig)
            cla(app.UIDFig)

        end

        function plotIDLR(app,isotope)
            %---------------------------------------------------------------------------------
            % Function: ID vs LR plot
            % This function creates Th232 and U238 isotopic diution vs linear regression
            % plots.
            %% -------------------------------------------------------------------------------

            if strcmp(isotope,'Th232(ID)')
                xIDFig = 'ThIDFig';
            elseif strcmp(isotope,'U238(ID)')
                xIDFig = 'UIDFig';
            end
            iRx = find(strcmp(app.Output.TE.IsotopesID,isotope));
            xLR = app.Output.TE.C.DC.Sample(iRx,~isnan(app.Output.TE.C.ID.Sample(iRx,1:end)));
            xID = app.Output.TE.C.ID.Sample(iRx,~isnan(app.Output.TE.C.ID.Sample(iRx,1:end)));
            dxLR = app.Output.TE.dC.DC.Sample(iRx,~isnan(app.Output.TE.C.ID.Sample(iRx,1:end)));
            dxID = app.Output.TE.dC.ID.Sample(iRx,~isnan(app.Output.TE.C.ID.Sample(iRx,1:end)));
            [px,~] = polyfit(xLR,xID,1);
            fTh = polyval(px,xLR);
            app.(xIDFig).NextPlot = 'replace';
            errorbar(app.(xIDFig),xLR,xID,dxID,dxID,dxLR,dxLR,'.k','MarkerSize',10,'LineStyle','none')
            line(app.(xIDFig),xLR,fTh,'LineStyle','-','Color','k')
            app.(xIDFig).XLim = [min([app.(xIDFig).XLim(1),app.(xIDFig).YLim(1)]),max([app.(xIDFig).XLim(2),app.(xIDFig).YLim(2)])];
            app.(xIDFig).YLim = [min([app.(xIDFig).XLim(1),app.(xIDFig).YLim(1)]),max([app.(xIDFig).XLim(2),app.(xIDFig).YLim(2)])];
            text(app.(xIDFig),app.(xIDFig).XLim(1)+(app.(xIDFig).XLim(2)-app.(xIDFig).XLim(1))*0.1,app.(xIDFig).YLim(1)+(app.(xIDFig).YLim(2)-app.(xIDFig).YLim(1))*0.9,4,['y = ',num2str(px(1),'%.2f'),'x + ',num2str(px(2),'%.2f')],'FontName','Courier')
            app.(xIDFig).XGrid = 'on';
            app.(xIDFig).YGrid = 'on';
            if strcmp(isotope,'Th232(ID)')
                app.(xIDFig).XLabel.String = '232Th, LR (ppm)';
                app.(xIDFig).YLabel.String = '232Th, ID (ppm)';
            elseif strcmp(isotope,'U238(ID)')
                app.(xIDFig).XLabel.String = '238U, LR (ppm)';
                app.(xIDFig).YLabel.String = '238U, ID (ppm)';
            end
            app.(xIDFig).FontName = 'Courier';
        end

        function discard = CheckForChanges(app,msg,button)
            %---------------------------------------------------------------------------------
            % Function: Check for unsaved changes
            % This function scans the input for unsaved changes. Executed prior to loading a
            % new batch file, clearing the current input, and quitting ICPro.
            %% -------------------------------------------------------------------------------

            % Get all input data, parameters and selections
            INx = getInput(app);
            if isfield(INx,'WtPar')
                INx = rmfield(INx,'WtPar');
            end

            if isfile(fullfile(app.AppPath.folder,'misc','Batch Input Files',app.BatchName))
                % Get currently loaded batch file or new batch window state (saved as ~newbatch.mat)
                if ~strcmp(app.ICProUIFigure.Name,'ICPro - unnamed batch')
                    load(fullfile(app.AppPath.folder,'misc','Batch Input Files',app.BatchName),'IN');
                    if isfield(IN,'WtPar')
                        IN = rmfield(IN,'WtPar');
                    end
                else
                    INx.BatchName = {'~newbatch'};
                    load(fullfile(app.AppPath.folder,'misc','Batch Input Files','~newbatch.mat'),'IN');
                end

                % Compare input to batch file, in case of difference initiate 'unsaved changes' prompt
                if ~isequaln(IN,INx)
                    title = 'Unsaved changes';
                    selection = questdlg(msg,title,button,'Cancel',button);
                    if strcmp(selection,button)
                        discard = true;
                    else
                        discard = false;
                    end
                else
                    discard = true;
                end
            else
                discard = true;
            end

            clearvars('INx')
        end

        function macopen(~,inp)
            %---------------------------------------------------------------------------------
            % MACOPEN Open a file or directory using the OPEN terminal utility on the MAC.
            %   MACOPEN FILENAME opens the file or directory FILENAME using the
            %   the OPEN terminal command.
            %
            %   Examples:
            %
            %     If you have Microsoft Word installed, then
            %     macopen('/myDoc.docx')
            %     opens that file in Microsoft Word if the file exists, and errors if
            %     it doesn't.
            %
            %     macopen('/Applications')
            %     opens a new Finder window, showing the contents of your /Applications
            %     folder.
            %
            %   See also WINOPEN, OPEN, DOS, WEB.
            %   Copyright 2012 - 2020 The MathWorks Inc.
            %   Written: 16-Apr-2012 , Varun Gandhi
            %% -------------------------------------------------------------------------------
            if strcmpi('.',inp)
                inp = pwd;
            end
            syscmd = ['open "', inp, '" &'];
            %             disp(['Running the following in the Terminal: "', syscmd,'"']);
            system(syscmd);

        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Splash(app)
            %---------------------------------------------------------------------------------
            % Callback: Splash screen
            % This function displays a splash screen when ICPro is launched
            %% -------------------------------------------------------------------------------

            % Add ICPro functions path to Matlab path
            addpath(fullfile(app.AppPath.folder,'misc','functions'))
            addpath(fullfile(app.AppPath.folder,'misc','icons'))
            warning('off','MATLAB:table:ModifiedAndSavedVarnames')
            warning('off','MATLAB:Figure:FigureSavedToMATFile')

            % Load Preferences
            load(fullfile(app.AppPath.folder,'misc','functions','Preferences.mat'),'Preferences')
            app.Prefs = Preferences;

            % Add icon to run button
            set(app.Button_Run,'Icon',fullfile(app.AppPath.folder,'misc','icons','go.png'))

            % Splash screen
            pause(5)
            p = rand(5);
            s = SplashScreen( 'Splashscreen', fullfile(app.AppPath.folder,'misc','icons','appicon_square.png'), ...
                'ProgressBar', 'on', ...
                'ProgressPosition', 5, ...
                'ProgressRatio', 0.1 );
            s.addText(10, 280, 'Initialising...', 'FontSize', 14, 'Color', [.6 .6 .6], 'FontName', 'Avenir')
            pause(p(1))
            s.ProgressRatio = .2;
            pause(p(2))
            s.ProgressRatio = .6;
            pause(p(3))
            s.ProgressRatio = .8;
            pause(p(4))
            s.ProgressRatio = 1;
            pause(p(5))
            delete(s)

            % Check for updates
            UpdateICPro(app, 'startup')

        end

        % Button pushed function: Button_Run
        function Execute(app, event)
            %---------------------------------------------------------------------------------
            % Callback: Execute data processing
            % This function initiates the processing of TE and/or ThU raw data based on the
            % provided input parameters and selections. Results displayed in UI.
            %% -------------------------------------------------------------------------------

            % Get all input data, parameters and selections and save to app property 'Input' (no output)
            [~] = getInput(app);

            % Load current preferences
            load(fullfile(app.AppPath.folder,'misc','functions','Preferences.mat'),'Preferences')
            app.Prefs = Preferences;

            % Determine analyses' toggle state
            State.TE = app.AnalysisSelection.SelectedObject == app.Toggle_TE;
            State.ThU = app.AnalysisSelection.SelectedObject == app.Toggle_ThU;

            % Waitbar setup
            if State.TE
                w(1) = 0;
                w(2) = 1;
                msg{1} = 'Trace Element processing ...';
                msg{2} = {['Trace Element processing ', char(hex2dec('2713'))]};
            elseif State.ThU
                w(1) = 0;
                w(2) = 1;
                msg{1} = 'Th/U processing ...';
                msg{2} = {['Th/U processing ', char(hex2dec('2713'))]};
            end

            % Waitbar (intial)
            wbexist = findall(groot,'type','Figure','-and','tag','TMWWaitbar');
            if ~isempty(wbexist)
                close(wbexist)
            end
            wb = waitbar(w(1),msg{1});

            % Trace Element processing
            if State.TE
                % Read & process TE raw data
                app.Output.TE = TE_ReadRaw(app,1);
                app.Output.TE = TE_Processor(app);

                % Display results
                if app.Prefs.TE.Check_DC
                    % Display corrected concentrations in table (isotopic dilution)
                    app.Table_TEresults.Data = [app.Output.TE.C.DC.f_DC',app.Output.TE.C.ID.Sample'];
                    app.Table_TEresults.ColumnName = ['Dilution factor';app.Output.TE.IsotopesID];
                    app.Table_TEresults.RowName = app.Output.TE.RunID.Sample;
                    app.CUSelTE.SelectedObject = app.ConcTE;
                    setappdata(app.ICProUIFigure,'ActiveResultTE',app.DataSelTE.SelectedObject.Tag);

                    % Plot ID vs linear calibration
                    plotIDLR(app,'Th232(ID)')
                    plotIDLR(app,'U238(ID)')
                else
                    % Display raw concentrations in table
                    app.Table_TEresults.Data = app.Output.TE.C.Raw.Sample';
                    app.Table_TEresults.ColumnName = app.Output.TE.Isotopes;
                    app.Table_TEresults.RowName = app.Output.TE.RunID.Sample;
                    app.CUSelTE.SelectedObject = app.ConcTE;
                    setappdata(app.ICProUIFigure,'ActiveResultTE',app.DataSelTE.SelectedObject.Tag);

                    % Clear ID vs LR figures
                    cla(app.ThIDFig)
                    cla(app.UIDFig)
                end

                % Enable export menu items
                app.TEsheet.Enable = app.Prefs.TE.Check_DC;        % TE result sheet export
                app.IndResExp.Enable = 'on';                    % Individual result export menu
                app.TEIndRes.Enable = 'on';                     % TE individual result export menu
                app.Rawexport.Enable = 'on';                    % Individual raw data export
                app.RCexport.Enable = app.Prefs.TE.Check_RC;       % Individual rinse corrected data export
                app.QCexport.Enable = app.Prefs.TE.Check_QC;       % Individual QC corrected data export
                app.SCexport.Enable = app.Prefs.TE.Check_SC;       % Individual spike corrected data export
                app.OCexport.Enable = app.Prefs.TE.Check_OC;       % Individual oxide corrected data export
                app.BCexport.Enable = app.Prefs.TE.Check_BC;       % Individual blank corrected data export
                app.DCexport.Enable = app.Prefs.TE.Check_DC;       % Individual dilution corrected data export
                app.IDexport.Enable = app.Prefs.TE.Check_DC;       % Individual isotopic dilution corrected data export
                app.IDLRplots.Enable = 'on';                    % ID vs LR plot export

                % Enable result switcher buttons and preselect results to preview
                app.RawButton.Enable = 'on';                    % Raw data selection button
                app.RCButton.Enable = app.Prefs.TE.Check_RC;       % Rinse correceted data selection button
                app.QCButton.Enable = app.Prefs.TE.Check_QC;       % QC correceted data selection button
                app.SCButton.Enable = app.Prefs.TE.Check_SC;       % Spike correceted data selection button
                app.OCButton.Enable = app.Prefs.TE.Check_OC;       % Oxide correceted data selection button
                app.BCButton.Enable = app.Prefs.TE.Check_BC;       % Blank correceted data selection button
                app.DCButton.Enable = app.Prefs.TE.Check_DC;       % Dilution correceted data selection button
                app.IDButton.Enable = app.Prefs.TE.Check_DC;       % Isotopic dilution correceted data selection button
                app.ConcTE.Enable = 'on';                       % Concentration selection button
                app.ErrTE.Enable = 'on';                        % Error selection button
                app.ErrpTE.Enable = 'on';                       % Percentage error selection button
                app.CUSelTE.SelectedObject = app.ConcTE;        % Preselect concentration display
                if app.Prefs.TE.Check_DC
                    app.DataSelTE.SelectedObject = app.IDButton;    % Preselect isotopic dilution corrected data
                else
                    app.DataSelTE.SelectedObject = app.RawButton;   % Preselect raw data (if no dilution correction was performed)
                end
            else
                % Clear TE result table and ID vs LR figures
                app.Table_TEresults.Data = [];
                cla(app.ThIDFig)
                cla(app.UIDFig)

                % Enable export menu items
                app.TEsheet.Enable = 'off';        % TE result sheet export
                %                 app.IndResExp.Enable = 'off';                    % Individual result export menu
                app.TEIndRes.Enable = 'off';                     % TE individual result export menu
                app.Rawexport.Enable = 'off';                    % Individual raw data export
                app.RCexport.Enable = app.Prefs.TE.Check_RC;       % Individual rinse corrected data export
                app.QCexport.Enable = app.Prefs.TE.Check_QC;       % Individual QC corrected data export
                app.SCexport.Enable = app.Prefs.TE.Check_SC;       % Individual spike corrected data export
                app.OCexport.Enable = app.Prefs.TE.Check_OC;       % Individual oxide corrected data export
                app.BCexport.Enable = app.Prefs.TE.Check_BC;       % Individual blank corrected data export
                app.DCexport.Enable = app.Prefs.TE.Check_DC;       % Individual dilution corrected data export
                app.IDexport.Enable = app.Prefs.TE.Check_DC;       % Individual isotopic dilution corrected data export
                app.IDLRplots.Enable = 'off';                    % ID vs LR plot export

                % Enable result switcher buttons and preselect results to preview
                app.RawButton.Enable = 'off';                    % Raw data selection button
                app.RCButton.Enable = app.Prefs.TE.Check_RC;       % Rinse correceted data selection button
                app.QCButton.Enable = app.Prefs.TE.Check_QC;       % QC correceted data selection button
                app.SCButton.Enable = app.Prefs.TE.Check_SC;       % Spike correceted data selection button
                app.OCButton.Enable = app.Prefs.TE.Check_OC;       % Oxide correceted data selection button
                app.BCButton.Enable = app.Prefs.TE.Check_BC;       % Blank correceted data selection button
                app.DCButton.Enable = app.Prefs.TE.Check_DC;       % Dilution correceted data selection button
                app.IDButton.Enable = app.Prefs.TE.Check_DC;       % Isotopic dilution correceted data selection button
                app.ConcTE.Enable = 'off';                       % Concentration selection button
                app.ErrTE.Enable = 'on';                        % Error selection button
                app.ErrpTE.Enable = 'on';                       % Percentage error selection button
                app.CUSelTE.SelectedObject = app.ConcTE;        % Preselect concentration display
                if app.Prefs.TE.Check_DC
                    app.DataSelTE.SelectedObject = app.IDButton;    % Preselect isotopic dilution corrected data
                else
                    app.DataSelTE.SelectedObject = app.RawButton;   % Preselect raw data (if no dilution correction was performed)
                end
            end

            % Th/U processing
            if app.AnalysisSelection.SelectedObject == app.Toggle_ThU
                % Calculate Th/U ratios from ICP-MS raw counts & Th230/U236 isotopic dilution
                app.Output.ThU = ThU_Processor(app,1);
                ThUSampleInd = find(~cellfun(@isempty,app.Output.ThU.SampleSeq(1:end)));

                % Display recovery plot
                recov229 = app.Output.ThU.Ratios.Sample.cpsMean(1,:,:);
                recov236 = app.Output.ThU.Ratios.Sample.cpsMean(7,:,:);
                app.RecoveryFig.NextPlot = 'replace';
                yyaxis(app.RecoveryFig,'left')
                set(app.RecoveryFig,'YColor',[0 0 0])
                bar(app.RecoveryFig,(.85:numel(recov229(ThUSampleInd))-.15),recov229(ThUSampleInd),0.3,'k')
                app.RecoveryFig.YLabel.String = 'raw counts [cps]';
                yyaxis(app.RecoveryFig,'right')
                set(app.RecoveryFig,'YColor',[.5 .5 .5])
                app.RecoveryFig.YLabel.String = 'raw counts [cps]';
                b = bar(app.RecoveryFig,(1.15:numel(recov236(ThUSampleInd))+.15),recov236(ThUSampleInd),0.3);
                b.FaceColor = [.7 .7 .7];
                app.RecoveryFig.XTick = 1:numel(recov236(ThUSampleInd));
                app.RecoveryFig.XTickLabel = app.Output.ThU.SampleSeq(ThUSampleInd);
                legend(app.RecoveryFig,'^{229}Th','^{236}U','NumColumns',2,'Location','north')
                app.RecoveryFig.Units = 'pixels';
                app.RecoveryFig.Position = [34,33,570,457];
                app.RecoveryFig.PlotBoxAspectRatioMode = 'auto';
                app.RecoveryFig.FontName = 'Courier';
                app.RecoveryFig.XTickLabelRotation = 45;
                app.RecoveryFig.TickLabelInterpreter = 'none';
                app.RecoveryFig.FontSize = 12;

                % Display results in table
                app.ThUIDButton.Value = 1;
                app.ConcThU.Value = 1;
                if ~isempty(app.Field_BlankID.Value)
                    app.Table_ThUresults.Data = [app.Output.ThU.ID.Th230BC,app.Output.ThU.ID.U234BC];
                    app.Table_ThUresults.ColumnName = {'Th230 (ID), blank corrected','U234 (ID), blank corrected'};
                    app.ThUBCcheck.Enable = 'on';
                    app.ThUBCcheck.Value = 1;
                else
                    app.Table_ThUresults.Data = [app.Output.ThU.ID.Th230,app.Output.ThU.ID.U234];
                    app.Table_ThUresults.ColumnName = {'Th230 (ID)','U234 (ID)'};
                    app.ThUBCcheck.Enable = 'off';
                    app.ThUBCcheck.Value = 0;
                end
                app.Table_ThUresults.RowName = app.Output.ThU.SampleSeq(ThUSampleInd);
                app.CUSelThU.SelectedObject = app.ConcThU;
                app.ConcThU.Text = '  Conc. (pg/g)';
                app.ErrThU.Text = '  Errors (pg/g)';
                setappdata(app.ICProUIFigure,'ActiveResultThU',app.DataSelThU.SelectedObject.Tag);

                % Enable export menu items
                app.ThUsheet.Enable = 'on';                 % Th/U result sheet export
                app.IndResExp.Enable = 'on';                % Individual result export menu
                app.ThUIndRes.Enable = 'on';                % Th/U individual result export menu
                app.ThUIDExp.Enable = 'on';                 % Individual Th & U isotopic dilution export
                app.SamRatioExp.Enable = 'on';              % Individual sample Th & U ratio export
                app.NatURatioExp.Enable = 'on';             % Individual NatU Th & U ratio export

                % Enable result switcher buttons and preselect results to preview
                app.ThUIDButton.Enable = 'on';              % Th & U isotopic dilution button
                app.RatioSButton.Enable = 'on';             % Sample Th & U ratio button
                app.RatioNButton.Enable = 'on';             % NatU Th & U ratio button
                app.ConcThU.Enable = 'on';                  % Concentration/Ratio selection button
                app.ErrThU.Enable = 'on';                   % Error selection button
                app.ErrpThU.Enable = 'on';                  % Percentage error slection button
                app.CUSelThU.SelectedObject = app.ConcThU;       % Preselect concentration display
                app.DataSelThU.SelectedObject = app.ThUIDButton; % Preselect isotopic dilution display
                app.ThUBCcheck.Enable = 'on';               % Blank correction check
            else
                yyaxis(app.RecoveryFig,'left')
                cla(app.RecoveryFig)
                yyaxis(app.RecoveryFig,'right')
                cla(app.RecoveryFig)
                app.Table_ThUresults.Data = [];
                % Enable export menu items
                app.ThUsheet.Enable = 'off';                 % Th/U result sheet export
                %                 app.IndResExp.Enable = 'off';                % Individual result export menu
                app.ThUIndRes.Enable = 'off';                % Th/U individual result export menu
                app.ThUIDExp.Enable = 'off';                 % Individual Th & U isotopic dilution export
                app.SamRatioExp.Enable = 'off';              % Individual sample Th & U ratio export
                app.NatURatioExp.Enable = 'off';             % Individual NatU Th & U ratio export

                % Enable result switcher buttons and preselect results to preview
                app.ThUIDButton.Enable = 'off';              % Th & U isotopic dilution button
                app.RatioSButton.Enable = 'off';             % Sample Th & U ratio button
                app.RatioNButton.Enable = 'off';             % NatU Th & U ratio button
                app.ConcThU.Enable = 'off';                  % Concentration/Ratio selection button
                app.ErrThU.Enable = 'off';                   % Error selection button
                app.ErrpThU.Enable = 'off';                  % Percentage error slection button
                app.CUSelThU.SelectedObject = app.ConcThU;       % Preselect concentration display
                app.DataSelThU.SelectedObject = app.ThUIDButton; % Preselect isotopic dilution display
                app.ThUBCcheck.Enable = 'off';               % Blank correction check
            end

            % Waitbar (end)
            wb = waitbar(w(end),wb,msg{end});

            AnalysisToggle(app,event)

            % Bring ICPro to foreground
            t = timer('ExecutionMode','singleShot','StartDelay',1,'TimerFcn',@(~,~)close(wb));
            start(t)

        end

        % Selection changed function: AnalysisSelection
        function AnalysisToggle(app, event)
            %---------------------------------------------------------------------------------
            % Callback: (Un)toggle ThU and TE processing
            % This function toggles or untoggles the processing of TE and ThU. Based on the
            % processing steps selected, required and obsolete input fields will be enabled
            % and disbaled, respectively.
            %% -------------------------------------------------------------------------------

            if event.Source == app.AnalysisSelection || event.Source == app.ClearBatchMenu || event.Source == app.Field_RawDataPath || event.Source == app.LoadBatchMenu
                % Determine analyses' toggle state
                State.TE = app.AnalysisSelection.SelectedObject == app.Toggle_TE;
                State.ThU = app.AnalysisSelection.SelectedObject == app.Toggle_ThU;

                % Get all fields which need to be enabled or disabled
                Th229SpikeFields = findall(app.Panel_Spikes,'-regexp','Tag','229','-and','-regexp','Type','DropDown','-or','-regexp','Tag','229','-and','-regexp','Type','NumericEditField');
                Th229SpikeButtns = findall(app.Panel_Spikes,'-regexp','Tag','229','-and','-regexp','Type','Button');
                Th230SpikeFields = findall(app.Panel_Spikes,'-regexp','Tag','230','-and','-regexp','Type','DropDown','-or','-regexp','Tag','230','-and','-regexp','Type','NumericEditField');
                Th230SpikeButtns = findall(app.Panel_Spikes,'-regexp','Tag','230','-and','-regexp','Type','Button');
                Th236SpikeFields = findall(app.Panel_Spikes,'-regexp','Tag','236','-and','-regexp','Type','DropDown','-or','-regexp','Tag','236','-and','-regexp','Type','NumericEditField');
                Th236SpikeButtns = findall(app.Panel_Spikes,'-regexp','Tag','236','-and','-regexp','Type','Button');
                if event.Source == app.AnalysisSelection || event.Source == app.ClearBatchMenu || event.Source == app.Field_RawDataPath || event.Source == app.LoadBatchMenu
                    TEResuTabFields = [];
                    ThUResTabFields = [];
                else
                    TEResuTabFields = findall(app.Tab_TEresults,'-regexp','Type','ToggleButton','-or','-regexp','Type','RadioButton');
                    ThUResTabFields = findall(app.Tab_ThUresults,'-regexp','Type','ToggleButton','-or','-regexp','Type','RadioButton');
                end

                % Determine fields to disable/enable based on analysis selected
                if State.TE
                    if true(app.Prefs.TE.Check_DC)
                        % DISABLE
                        DisableFields = [...
                            ThUResTabFields;...                             % [-] Th/U results tab
                            Th229SpikeFields;Th229SpikeButtns...            % [-] Th229 spike fields and buttons (incl. U236 RYb)
                            ];
                        % ENABLE
                        EnableFields = [...
                            Th236SpikeFields;Th236SpikeButtns;...           % [+] Th236 spike fields and buttons (excl. U236 RYa/b)
                            Th230SpikeFields;Th230SpikeButtns;...           % [+] Th230 spike fields and buttons (incl. U236 RYa)
                            ];
                    else
                        % DISABLE
                        DisableFields = [...
                            Th229SpikeFields;Th229SpikeButtns;...           % [-] Th229 spike fields and buttons (incl. U236 RYb)
                            Th230SpikeFields;Th230SpikeButtns;...           % [-] Th230 spike fields and buttons (incl. U236 RYa)
                            Th236SpikeFields;Th236SpikeButtns;...           % [-] Th236 spike fields and buttons (excl. U236 RYa/b)
                            ThUResTabFields;...                             % [-] Th/U results tab
                            ];
                        % ENABLE
                        EnableFields = [...
                            ];
                    end
                    app.Panel_ThUResults.Visible = 'off';
                    app.Panel_ThUResults.Enable = 'off';
                    app.Panel_TEResults.Visible = 'on';
                    app.Panel_TEResults.Enable = 'on';

                elseif State.ThU
                    % DISABLE
                    DisableFields = [...
                        TEResuTabFields;...                                 % [-] TE results
                        Th230SpikeFields;Th230SpikeButtns...                % [-] Th230 spike fields and buttons (incl. U236 RYa)
                        ];
                    % ENABLE
                    EnableFields = [...
                        Th236SpikeFields;Th236SpikeButtns;...               % [+] Th236 spike fields and buttons (excl. U236 RYa/b)
                        Th229SpikeFields;Th229SpikeButtns;...               % [+] Th229 spike fields and buttons (incl. U236 RYb)
                        ];
                    app.Panel_ThUResults.Visible = 'on';
                    app.Panel_ThUResults.Enable = 'on';
                    app.Panel_TEResults.Visible = 'off';
                    app.Panel_TEResults.Enable = 'off';
                end

                if app.DropDown_229.Value == 0
                    DisableFields = [DisableFields;Th229SpikeButtns];
                else
                    EnableFields = [EnableFields;Th229SpikeButtns];
                end
                if app.DropDown_230.Value == 0
                    DisableFields = [DisableFields;Th230SpikeButtns];
                else
                    EnableFields = [EnableFields;Th230SpikeButtns];
                end
                if app.DropDown_236.Value == 0
                    DisableFields = [DisableFields;Th236SpikeButtns];
                else
                    EnableFields = [EnableFields;Th236SpikeButtns];
                end

                % Disable and enable fields
                for iE = 1 : length(EnableFields)
                    EnableFields(iE).Enable = 'on';
                end
                for iD = 1 : length(DisableFields)
                    DisableFields(iD).Enable = 'off';
                end

            end
        end

        % Button pushed function: Button_RawDataPath
        function BrowseRawData(app, event)
            %---------------------------------------------------------------------------------
            % Callback: File browser UI
            % This function initiates a file browser UI, allowing the user to locate raw data
            % files and folders.
            %% -------------------------------------------------------------------------------

            if app.AnalysisSelection.SelectedObject == app.Toggle_TE
                [TERawFile,TERawPath] = uigetfile('.xlsx','Pick a Trace Element raw file');
                if TERawPath ~= 0
                    set(app.Field_RawDataPath,'Value',[TERawPath,TERawFile])
                end
            elseif app.AnalysisSelection.SelectedObject == app.Toggle_ThU
                ThURawPath = uigetdir('','Pick a Th/U raw data folder');
                if ThURawPath ~= 0
                    set(app.Field_RawDataPath,'Value',ThURawPath)
                end
            end

            % Bring ICPro to foreground
            figure(app.ICProUIFigure)
        end

        % Button pushed function: Button_InputSheet
        function InputSheet(app, event)
            %---------------------------------------------------------------------------------
            % Callback: Generate sample ID list
            % This function generates an input spreadsheet for weight inputs
            %% -------------------------------------------------------------------------------


            % Check for complete input
            AllInput = ~isempty(app.Field_RawDataPath.Value) && ~isempty(app.Field_SampleID.Value) && ~isempty(app.Field_BlankID.Value);

            if AllInput
                % Get input
                [~] = getInput(app);

                % Determine analyses' toggle state
                State.TE = app.AnalysisSelection.SelectedObject == app.Toggle_TE;
                State.ThU = app.AnalysisSelection.SelectedObject == app.Toggle_ThU;

                if State.TE
                    % Get TE IDs by running reduced version of TE_ReadRaw (additional input: 0)
                    app.Output.TE = TE_ReadRaw(app,0);

                    % Generate input spreadsheet
                    Fill = zeros(numel([app.Output.TE.RunID.Blank;app.Output.TE.RunID.Sample]),11);
                    ColumnHeader = {'Sediment wt. in mg','4 ml wt. in mg','10 ml wt. in mg','400 ml wt. in mg',...
                        '230Th spike wt. in mg','236U (small cut) spike wt. in mg','236U (big cut) spike wt. in mg','Th230 cps spike wt. in mg',...
                        'Th232 raw counts','U236 raw counts','U238 raw counts'};
                    InputSpreadsheet = table(Fill(:,1),Fill(:,2),Fill(:,3),Fill(:,4),Fill(:,5),Fill(:,6),Fill(:,7),Fill(:,8),Fill(:,9),Fill(:,10),Fill(:,11),...
                        'RowName',[app.Output.TE.RunID.Blank;app.Output.TE.RunID.Sample],...
                        'VariableNames',ColumnHeader);
                    InputSpreadsheet.Properties.DimensionNames{1} = 'Samples';

                    TErawfileDir = dir(app.Input.AnalysisInfo.RawPath);
                    if ~isfile(fullfile(app.Input.AnalysisInfo.RawPath,'TE_WtInputTable.xlsx'))
                        writetable(InputSpreadsheet,fullfile(TErawfileDir.folder,'TE_WtInputTable.xlsx'),'WriteRowNames',true)
                    end

                    if ispc
                        winopen(fullfile(TErawfileDir.folder,'TE_WtInputTable.xlsx'))
                    else
                        macopen(app,fullfile(TErawfileDir.folder,'TE_WtInputTable.xlsx'))
                    end
                end

                if State.ThU
                    % Get ThU IDs by running reduced version of ThU_Processor (additional input: 0)
                    app.Output.ThU = ThU_Processor(app,0);
                    ThUSampleInd = find(~cellfun(@isempty,app.Output.ThU.SampleSeq(1:end)));

                    % Generate input spreadsheet
                    Fill = zeros(numel(app.Output.ThU.SampleSeq(ThUSampleInd)),3);
                    ColumnHeader = {'Sediment wt. in mg','229Th spike wt. in mg','236U (big cut) in mg'};
                    InputSpreadsheet = table(Fill(:,1),Fill(:,2),Fill(:,3),...
                        'RowName',app.Output.ThU.SampleSeq(ThUSampleInd),...
                        'VariableNames',ColumnHeader);
                    InputSpreadsheet.Properties.DimensionNames{1} = 'Samples';

                    if ~isfile(fullfile(app.Input.AnalysisInfo.RawPath,'ThU_WtInputTable.xlsx'))
                        writetable(InputSpreadsheet,fullfile(app.Input.AnalysisInfo.RawPath,'ThU_WtInputTable.xlsx'),'WriteRowNames',true)
                    end

                    if ispc
                        winopen(fullfile(app.Input.AnalysisInfo.RawPath,'ThU_WtInputTable.xlsx'))
                    else
                        macopen(app,fullfile(app.Input.AnalysisInfo.RawPath,'ThU_WtInputTable.xlsx'))
                    end

                end
            end

            if 1 == 0
                % Get input
                [~] = getInput(app);

                % Load current preferences
                load(fullfile(app.AppPath.folder,'misc','Preferences.mat'),'Preferences')
                app.Prefs = Preferences;

                % Determine analyses' toggle state
                State.TE = app.AnalysisSelection.SelectedObject == app.Toggle_TE;
                State.ThU = app.AnalysisSelection.SelectedObject == app.Toggle_ThU;

                % CASE 1: TE + dilution correction
                if State.TE
                    % Get TE IDs by running reduced version of TE_ReadRaw (input 0)
                    app.Output.TE = TE_ReadRaw(app,0);

                    % Generate list or display warning message
                    if ~isempty(app.Output.TE.RunID.Sample) && ~isempty(app.Output.TE.RunID.Blank)
                        % If Sample and Blank ID were identified correctly proceed to generate list
                        set(app.Field_SampleList,'Visible','on')
                        set(app.Field_SampleList,'Enable','on')
                        set(app.Field_SampleList,'Editable','off')
                        set(app.Field_SampleList,'Value',[app.Output.TE.RunID.Blank;app.Output.TE.RunID.Sample])
                        set(app.Field_cpsSampleList,'Visible','on')
                        set(app.Field_cpsSampleList,'Enable','on')
                        set(app.Field_cpsSampleList,'Editable','off')
                        set(app.Field_cpsSampleList,'Value',[app.Output.TE.RunID.Blank;app.Output.TE.RunID.Sample])

                        set(app.WtInputTable,'Data',zeros(numel([app.Output.TE.RunID.Blank;app.Output.TE.RunID.Sample]),11))
                        set(app.WtInputTable,'ColumnName',{'Sediment wt.','4 ml wt.','10 ml wt.','400 ml wt.','229Th','230Th','236U (SC)','Th230 cps','Th232 cps','U236 cps','U238 cps'});
                        set(app.WtInputTable,'RowName',[app.Output.TE.RunID.Blank;app.Output.TE.RunID.Sample])
                    else
                        % If incorrect ID inputs were provided no Blank/Sample ID will be detected (warning message)
                        if isempty(app.Output.TE.RunID.Blank)
                            warndlg('Procedural Blank ID not found in file. Check ID provided.','Incorrect input')
                            set(app.Field_SampleList,'Visible','off')
                            set(app.Button_InputSheet,'Visible','on')
                            set(app.Button_InputSheet,'Enable','on')
                        end
                        if isempty(app.Output.TE.RunID.Sample)
                            warndlg('Sample ID initials not found in file. Check sample initials provided.','Incorrect input parameters')
                            set(app.Field_SampleList,'Visible','off')
                            set(app.Button_InputSheet,'Visible','on')
                            set(app.Button_InputSheet,'Enable','on')
                        end
                        set(app.Field_SampleList,'Visible','off')
                        set(app.Button_InputSheet,'Visible','on')
                        set(app.Button_InputSheet,'Enable','on')
                    end
                end

                % CASE 2: ThU
                if State.ThU
                    app.Output.ThU = ThU_Processor(app,0);

                    % If Sample ID was identified correctly proceed to generate list
                    if isstruct(app.Output.ThU)
                        ThUSampleInd = find(~cellfun(@isempty,app.Output.ThU.SampleSeq(1:end)));
                        set(app.Field_SampleList,'Visible','on')
                        set(app.Field_SampleList,'Enable','on')
                        set(app.Field_SampleList,'Editable','off')
                        set(app.Field_SampleList,'Value',app.Output.ThU.SampleSeq(ThUSampleInd))
                        set(app.Field_cpsSampleList,'Visible','on')
                        set(app.Field_cpsSampleList,'Enable','on')
                        set(app.Field_cpsSampleList,'Editable','off')
                        set(app.Field_cpsSampleList,'Value',app.Output.ThU.SampleSeq(ThUSampleInd))

                        set(app.WtInputTable,'Data',zeros(numel(app.Output.ThU.SampleSeq(ThUSampleInd)),3))
                        set(app.WtInputTable,'RowName',app.Output.ThU.SampleSeq(ThUSampleInd))
                        set(app.WtInputTable,'ColumnName',{'Sediment wt.','229Th','236U (BC)'});
                    else
                        set(app.Field_SampleList,'Visible','off')
                    end
                end
                % Bring ICPro to foreground
                figure(app.ICProUIFigure)
            end
        end

        % Callback function: Button_Delete229, Button_Delete230, 
        % Button_Delete236, Button_EdtSve229, Button_EdtSve230, 
        % Button_EdtSve236, DropDown_229, DropDown_229, 
        % DropDown_230, DropDown_230, DropDown_236, DropDown_236
        function Spikes(app, event)
            %---------------------------------------------------------------------------------
            % Callback: Spike control
            % This function collectively manages the user's interaction with the spike panel:
            % - Spike selection via dropdown menu
            % - Adding and deleting spikes
            % - Editing spike parameters
            % - Automatic spike selection from imported batch file
            %% -------------------------------------------------------------------------------

            % App directory
            SpikeSheetPath = fullfile(app.AppPath.folder,'misc','spikes.xlsx');

            % Determine spike function to run
            if event.Source ~= app.LoadBatchMenu && event.Source ~= app.ClearBatchMenu && event.Source ~= app.DeleteBatchMenu
                SpikeFun = event.Source.Tag(1:3);
                SpikeIso = event.Source.Tag(4:6);
                if strcmp(SpikeIso,'229')
                    SpikeIsoLong = 'Th229';
                elseif strcmp(SpikeIso,'230')
                    SpikeIsoLong = 'Th230';
                elseif strcmp(SpikeIso,'236')
                    SpikeIsoLong = 'U236';
                end
            elseif event.Source == app.LoadBatchMenu
                SpikeFun = 'loadbatch';
                SpikeIso = {'229','230','236'};
                SpikeIsoLong = {'Th229','Th230','U236'};
            elseif event.Source == app.ClearBatchMenu || event.Source == app.DeleteBatchMenu
                SpikeFun = 'clearbatch';
                SpikeIso = {'229','230','236'};
                SpikeIsoLong = {'Th229','Th230','U236'};
            end

            % Spike dropdown (get/select spikes)
            if strcmp(SpikeFun,'get')
                opts = detectImportOptions(SpikeSheetPath,'Sheet',SpikeIsoLong);
                spikes = readtable(SpikeSheetPath,opts);
                spikesInf = spikes{:,2:end};
                spikesInf(spikesInf == 65535) = Inf;
                spikes{:,2:end} = spikesInf;
                if strcmp(event.EventName,'DropDownOpening')
                    % Create spike list when dropdown is opened
                    set(app.(['DropDown_',SpikeIso]),'Items',['Select';spikes{:,1};'Add'])
                    set(app.(['DropDown_',SpikeIso]),'ItemsData',[0;(1:length(spikes{:,1}))';999])
                elseif strcmp(event.EventName,'ValueChanged')
                    % Change to selected spike
                    value = app.(['DropDown_',SpikeIso]).Value;
                    if value > 0 && value < 999
                        set(app.(['Field_RY',SpikeIso]),'Value',spikes{value,2})
                        set(app.(['Field_CY',SpikeIso]),'Value',spikes{value,3})
                        if strcmp(SpikeIso,'236')
                            set(app.(['Field_RYb',SpikeIso]),'Value',spikes{value,4})
                        end
                        set(app.(['Field_CY',SpikeIso]),'Editable','off')
                        set(app.(['Field_RY',SpikeIso]),'Editable','off')
                        set(app.(['Button_Delete',SpikeIso]),'Enable','on')
                        set(app.(['Button_EdtSve',SpikeIso]),'Text','edit')
                        set(app.(['Button_EdtSve',SpikeIso]),'Tag',['edt',SpikeIso])
                        set(app.(['Button_EdtSve',SpikeIso]),'Enable','on')
                    elseif value == 0
                        set(app.(['Field_RY',SpikeIso]),'Value',Inf)
                        set(app.(['Field_CY',SpikeIso]),'Value',0)
                        set(app.(['Field_CY',SpikeIso]),'Editable','off')
                        set(app.(['Field_RY',SpikeIso]),'Editable','off')
                        if strcmp(SpikeIso,'236')
                            set(app.(['Field_RYb',SpikeIso]),'Value',Inf)
                            set(app.(['Field_RYb',SpikeIso]),'Editable','off')
                        end
                        set(app.(['Button_Delete',SpikeIso]),'Enable','off')
                        set(app.(['Button_EdtSve',SpikeIso]),'Text','edit')
                        set(app.(['Button_EdtSve',SpikeIso]),'Tag',['edt',SpikeIso])
                        set(app.(['Button_EdtSve',SpikeIso]),'Enable','off')
                    elseif value == 999
                        set(app.(['Field_CY',SpikeIso]),'Editable','on')
                        set(app.(['Field_RY',SpikeIso]),'Editable','on')
                        if strcmp(SpikeIso,'236')
                            set(app.(['Field_RYb',SpikeIso]),'Editable','on')
                            set(app.(['Field_RYb',SpikeIso]),'Enable','on')
                            set(app.(['Field_RY',SpikeIso]),'Enable','on')
                        end
                        set(app.(['Button_Delete',SpikeIso]),'Enable','off')
                        set(app.(['Button_EdtSve',SpikeIso]),'Text','save')
                        set(app.(['Button_EdtSve',SpikeIso]),'Tag',['sve',SpikeIso])
                        set(app.(['Button_EdtSve',SpikeIso]),'Enable','on')
                        newspikeID = inputdlg('Enter spike ID:');
                        if ~isempty(newspikeID)
                            set(app.(['DropDown_',SpikeIso]),'Items',['Select';spikes{:,1};newspikeID;'Add'])
                            set(app.(['DropDown_',SpikeIso]),'ItemsData',[0;(1:length(spikes{:,1})+1)';999])
                            set(app.(['DropDown_',SpikeIso]),'Value',length(spikes{:,1})+1)
                            set(app.(['Field_RY',SpikeIso]),'Value',0)
                            set(app.(['Field_CY',SpikeIso]),'Value',0)
                        else
                            app.(['DropDown_',SpikeIso]).Value = 0;
                            set(app.(['Field_RY',SpikeIso]),'Value',0)
                            set(app.(['Field_CY',SpikeIso]),'Value',0)
                            set(app.(['Field_CY',SpikeIso]),'Editable','off')
                            set(app.(['Field_RY',SpikeIso]),'Editable','off')
                            set(app.(['Button_Delete',SpikeIso]),'Enable','off')
                            set(app.(['Button_EdtSve',SpikeIso]),'Text','edit')
                            set(app.(['Button_EdtSve',SpikeIso]),'Tag',['edt',SpikeIso])
                            set(app.(['Button_EdtSve',SpikeIso]),'Enable','off')
                        end
                    end
                end
            end

            % Edit spikes
            if strcmp(SpikeFun,'edt')
                set(app.(['Field_CY',SpikeIso]),'Editable','on')
                set(app.(['Field_RY',SpikeIso]),'Editable','on')
                if strcmp(SpikeIso,'236')
                    set(app.(['Field_RYb',SpikeIso]),'Editable','on')
                    set(app.(['Field_RY',SpikeIso]),'Enable','on')
                    set(app.(['Field_RYb',SpikeIso]),'Enable','on')
                end
                set(app.(['Button_EdtSve',SpikeIso]),'Text','save')
                set(app.(['Button_EdtSve',SpikeIso]),'Tag',['sve',SpikeIso])
                set(app.(['Button_EdtSve',SpikeIso]),'Enable','on')
            end

            % Save spikes
            if strcmp(SpikeFun,'sve')
                value = app.(['DropDown_',SpikeIso]).Value;
                opts = detectImportOptions(SpikeSheetPath,'Sheet',SpikeIsoLong);
                spikes = readtable(SpikeSheetPath,opts);
                if value > length(spikes{:,1})
                    if strcmp(SpikeIso,'236')
                        spikes = [spikes;{0,0,0,0}];
                    else
                        spikes = [spikes;{0,0,0}];
                    end
                    spikes{end,1} = app.(['DropDown_',SpikeIso]).Items(app.(['DropDown_',SpikeIso]).Value+1);
                end
                spikes{value,2} = app.(['Field_RY',SpikeIso]).Value;
                spikes{value,3} = app.(['Field_CY',SpikeIso]).Value;
                if strcmp(SpikeIso,'236')
                    spikes{value,4} = app.(['Field_RYb',SpikeIso]).Value;
                end
                writetable(spikes,SpikeSheetPath,'Sheet',SpikeIsoLong,'WriteMode','overwritesheet')
                set(app.(['Field_CY',SpikeIso]),'Editable','off')
                set(app.(['Field_RY',SpikeIso]),'Editable','off')
                set(app.(['Field_RY',SpikeIso]),'Enable','off')
                if app.Toggle_TE.Value == 1
                    set(app.(['Field_RY',SpikeIso]),'Enable','on')
                end
                if strcmp(SpikeIso,'236')
                    set(app.(['Field_RYb',SpikeIso]),'Editable','off')
                    set(app.(['Field_RYb',SpikeIso]),'Enable','off')
                    if app.Toggle_ThU.Value == 1
                        set(app.(['Field_RYb',SpikeIso]),'Enable','on')
                    end
                end
                set(app.(['Button_EdtSve',SpikeIso]),'Text','edit')
                set(app.(['Button_EdtSve',SpikeIso]),'Tag',['edt',SpikeIso])
                set(app.(['Button_EdtSve',SpikeIso]),'Enable','on')
                set(app.(['Button_Delete',SpikeIso]),'Enable','on')
            end

            % Delete spikes
            if strcmp(SpikeFun,'dlt')
                value = app.(['DropDown_',SpikeIso]).Value;
                % Confirm dialog
                msg = ['Do you want to delete spike ''',app.(['DropDown_',SpikeIso]).Items{value+1},''''];
                title = 'Confirm Delete';
                selection = questdlg(msg,title,'Ok','Cancel','Ok');
                if strcmp(selection,'Ok')
                    opts = detectImportOptions(SpikeSheetPath,'Sheet',SpikeIsoLong);
                    spikes = readtable(SpikeSheetPath,opts);
                    spikes(value,:) = [];
                    writetable(spikes,SpikeSheetPath,'Sheet',SpikeIsoLong,'WriteMode','overwritesheet')
                    set(app.(['DropDown_',SpikeIso]),'Items',['Select';spikes{:,1};'Add'])
                    set(app.(['DropDown_',SpikeIso]),'ItemsData',[0;(1:length(spikes{:,1}))';999])
                    app.(['DropDown_',SpikeIso]).Value = 0;
                    set(app.(['Field_RY',SpikeIso]),'Value',0)
                    set(app.(['Field_CY',SpikeIso]),'Value',0)
                    set(app.(['Field_CY',SpikeIso]),'Editable','off')
                    set(app.(['Field_RY',SpikeIso]),'Editable','off')
                    if strcmp(SpikeIso,'236')
                        set(app.(['Field_RYb',SpikeIso]),'Value',0)
                        set(app.(['Field_RYb',SpikeIso]),'Editable','off')
                    end
                    set(app.(['Button_EdtSve',SpikeIso]),'Text','edit')
                    set(app.(['Button_EdtSve',SpikeIso]),'Tag',['edt',SpikeIso])
                    set(app.(['Button_EdtSve',SpikeIso]),'Enable','off')
                    set(app.(['Button_Delete',SpikeIso]),'Enable','off')
                end
            end

            % Load spikes of imported batch
            if strcmp(SpikeFun,'loadbatch')
                % Create spike list
                for i = 1 : 3
                    opts = detectImportOptions(SpikeSheetPath','Sheet',SpikeIsoLong{i});
                    spikes = readtable(SpikeSheetPath,opts);
                    spikesInf = spikes{:,2:end};
                    spikesInf(spikesInf == 65535) = Inf;
                    spikes{:,2:end} = spikesInf;
                    set(app.(['DropDown_',SpikeIso{i}]),'Items',['Select';spikes{:,1};'Add'])
                    set(app.(['DropDown_',SpikeIso{i}]),'ItemsData',[0;(1:length(spikes{:,1}))';999])
                    if isfield(app.Input,'SpikeSel')
                        set(app.(['DropDown_',SpikeIso{i}]),'Value',app.Input.SpikeSel(i))
                        if app.Input.SpikeSel(i) > 0 && app.Input.SpikeSel(i) < 999
                            set(app.(['Field_RY',SpikeIso{i}]),'Value',spikes{app.Input.SpikeSel(i),2})
                            set(app.(['Field_CY',SpikeIso{i}]),'Value',spikes{app.Input.SpikeSel(i),3})
                            if i == 3
                                set(app.(['Field_RYb',SpikeIso{i}]),'Value',spikes{app.Input.SpikeSel(i),4})
                            end
                            set(app.(['Button_Delete',SpikeIso{i}]),'Enable','on')
                            set(app.(['Button_EdtSve',SpikeIso{i}]),'Enable','on')
                        elseif app.Input.SpikeSel(i) == 0
                            set(app.(['Field_RY',SpikeIso{i}]),'Value',Inf)
                            set(app.(['Field_CY',SpikeIso{i}]),'Value',0)
                            if i == 3
                                set(app.(['Field_RYb',SpikeIso{i}]),'Value',Inf)
                            end
                            set(app.(['Button_Delete',SpikeIso{i}]),'Enable','off')
                            set(app.(['Button_EdtSve',SpikeIso{i}]),'Enable','off')
                        end
                        set(app.(['Field_CY',SpikeIso{i}]),'Editable','off')
                        set(app.(['Field_RY',SpikeIso{i}]),'Editable','off')
                    else
                        set(app.(['DropDown_',SpikeIso{i}]),'Value',0)
                        set(app.(['Field_RY',SpikeIso{i}]),'Value',Inf)
                        set(app.(['Field_CY',SpikeIso{i}]),'Value',0)
                        if i == 3
                            set(app.(['Field_RYb',SpikeIso{i}]),'Value',Inf)
                        end
                        set(app.(['Button_Delete',SpikeIso{i}]),'Enable','off')
                        set(app.(['Button_EdtSve',SpikeIso{i}]),'Enable','off')
                    end
                    set(app.(['Field_CY',SpikeIso{i}]),'Editable','off')
                    set(app.(['Field_RY',SpikeIso{i}]),'Editable','off')
                    if i == 3
                        set(app.(['Field_RYb',SpikeIso{i}]),'Editable','off')
                    end
                    set(app.(['Button_EdtSve',SpikeIso{i}]),'Text','edit')
                    set(app.(['Button_EdtSve',SpikeIso{i}]),'Tag',['edt',SpikeIso{i}])
                end
            end

            % Reset spikes for new batch
            if strcmp(SpikeFun,'clearbatch')
                % Create spike list
                for i = 1 : 3
                    set(app.(['DropDown_',SpikeIso{i}]),'Value',0)
                    set(app.(['Field_RY',SpikeIso{i}]),'Value',Inf)
                    set(app.(['Field_CY',SpikeIso{i}]),'Value',0)
                    set(app.(['Field_CY',SpikeIso{i}]),'Editable','off')
                    set(app.(['Field_RY',SpikeIso{i}]),'Editable','off')
                    if i == 3
                        set(app.(['Field_RYb',SpikeIso{i}]),'Value',Inf)
                        set(app.(['Field_RYb',SpikeIso{i}]),'Editable','off')
                    end
                    set(app.(['Button_Delete',SpikeIso{i}]),'Enable','off')
                    set(app.(['Button_EdtSve',SpikeIso{i}]),'Text','edit')
                    set(app.(['Button_EdtSve',SpikeIso{i}]),'Tag',['edt',SpikeIso{i}])
                    set(app.(['Button_EdtSve',SpikeIso{i}]),'Enable','off')
                end
            end

            % Bring ICPro to foreground
            %             figure(app.ICProUIFigure)
        end

        % Callback function: CUSelThU, DataSelThU, ThUBCcheck
        function SwitchResultThU(app, event)
            %---------------------------------------------------------------------------------
            % Callback: ThU result selection
            % This function manages the selection and display of ThU processing results.
            %% -------------------------------------------------------------------------------

            Selection = app.DataSelThU.SelectedObject.Tag;
            ThUSampleInd = find(~cellfun(@isempty,app.Output.ThU.SampleSeq(1:end)));
            if strcmp(app.CUSelThU.SelectedObject.Tag,'Conc')
                if strcmp(Selection,'SampleMB')
                    app.Table_ThUresults.Data = [app.Output.ThU.Ratios.Sample.R229_230_cMB(ThUSampleInd),app.Output.ThU.Ratios.Sample.R236_234_cMB(ThUSampleInd)];
                elseif strcmp(Selection,'NatUMB')
                    app.Table_ThUresults.Data = app.Output.ThU.Ratios.NatU.R235_234_cMB;
                elseif strcmp(Selection,'ThUID')
                    if app.ThUBCcheck.Value == 0
                        app.Table_ThUresults.Data = [app.Output.ThU.ID.Th230,app.Output.ThU.ID.U234];
                    else
                        app.Table_ThUresults.Data = [app.Output.ThU.ID.Th230BC,app.Output.ThU.ID.U234BC];
                    end
                end
            elseif strcmp(app.CUSelThU.SelectedObject.Tag,'Err')
                if strcmp(Selection,'SampleMB')
                    app.Table_ThUresults.Data = [app.Output.ThU.dRatios.Sample.R229_230_cMB(ThUSampleInd),app.Output.ThU.dRatios.Sample.R236_234_cMB(ThUSampleInd)];
                elseif strcmp(Selection,'NatUMB')
                    app.Table_ThUresults.Data = app.Output.ThU.dRatios.NatU.R235_234_cMB;
                elseif strcmp(Selection,'ThUID')
                    if app.ThUBCcheck.Value == 0
                        app.Table_ThUresults.Data = [app.Output.ThU.dID.Th230,app.Output.ThU.dID.U234];
                    else
                        app.Table_ThUresults.Data = [app.Output.ThU.dID.Th230BC,app.Output.ThU.dID.U234BC];
                    end
                end
            elseif strcmp(app.CUSelThU.SelectedObject.Tag,'Errp')
                if strcmp(Selection,'SampleMB')
                    app.Table_ThUresults.Data = [app.Output.ThU.dRatios.Sample.R229_230_cMB(ThUSampleInd)./app.Output.ThU.Ratios.Sample.R229_230_cMB(ThUSampleInd),app.Output.ThU.dRatios.Sample.R236_234_cMB(ThUSampleInd)./app.Output.ThU.Ratios.Sample.R236_234_cMB(ThUSampleInd)]*100;
                elseif strcmp(Selection,'NatUMB')
                    app.Table_ThUresults.Data = app.Output.ThU.dRatios.NatU.R235_234_cMB./app.Output.ThU.Ratios.NatU.R235_234_cMB*100;
                elseif strcmp(Selection,'ThUID')
                    if app.ThUBCcheck.Value == 0
                        app.Table_ThUresults.Data = [app.Output.ThU.dID.Th230./app.Output.ThU.ID.Th230,app.Output.ThU.dID.U234./app.Output.ThU.ID.U234]*100;
                    else
                        app.Table_ThUresults.Data = [app.Output.ThU.dID.Th230BC./app.Output.ThU.ID.Th230BC,app.Output.ThU.dID.U234BC./app.Output.ThU.ID.U234BC]*100;
                    end
                end
            end

            if strcmp(Selection,'SampleMB')
                app.ThUBCcheck.Enable = 0;
                app.ThUBCcheck.Visible = 0;
                app.Table_ThUresults.ColumnName = {'Th229/Th230','U236/U234'};
                app.Table_ThUresults.RowName = app.Output.ThU.SampleSeq(ThUSampleInd);
                app.ConcThU.Text = '  Ratios';
                app.ErrThU.Text = '  Errors';
            elseif strcmp(Selection,'NatUMB')
                app.ThUBCcheck.Enable = 0;
                app.ThUBCcheck.Visible = 0;
                app.Table_ThUresults.ColumnName = {'U235/U234'};
                app.Table_ThUresults.RowName = app.Output.ThU.NatUSeq;
                app.ConcThU.Text = '  Ratios';
                app.ErrThU.Text = '  Errors';
            elseif strcmp(Selection,'ThUID')
                app.ThUBCcheck.Enable = 1;
                app.ThUBCcheck.Visible = 1;
                if app.ThUBCcheck.Value == 0
                    app.Table_ThUresults.ColumnName = {'Th230 (ID)','U234 (ID)'};
                else
                    app.Table_ThUresults.ColumnName = {'Th230 (ID), blank corrected','U234 (ID), blank corrected'};
                end
                app.Table_ThUresults.RowName = app.Output.ThU.SampleSeq(ThUSampleInd);
                app.ConcThU.Text = '  Conc. (pg/g)';
                app.ErrThU.Text = '  Errors (pg/g)';
            end

            setappdata(app.ICProUIFigure,'ActiveResultThU',Selection);
        end

        % Selection changed function: CUSelTE, DataSelTE
        function SwitchResultTE(app, event)
            %---------------------------------------------------------------------------------
            % Callback: TE result selection
            % This function manages the selection and display of TE processing results.
            %% -------------------------------------------------------------------------------

            Selection = app.DataSelTE.SelectedObject.Tag;
            if strcmp(app.CUSelTE.SelectedObject.Tag,'Conc')
                if (strcmp(Selection,'ID') || strcmp(Selection,'DC')) && app.Prefs.TE.Check_DC == 1
                    app.Table_TEresults.Data = [app.Output.TE.C.DC.f_DC',app.Output.TE.C.(Selection).Sample'];
                else
                    app.Table_TEresults.Data = app.Output.TE.C.(Selection).Sample';
                end
            elseif strcmp(app.CUSelTE.SelectedObject.Tag,'Err')
                if (strcmp(Selection,'ID') || strcmp(Selection,'DC')) && app.Prefs.TE.Check_DC == 1
                    app.Table_TEresults.Data = [app.Output.TE.dC.DC.f_DC',app.Output.TE.dC.(Selection).Sample'];
                else
                    app.Table_TEresults.Data = app.Output.TE.dC.(Selection).Sample';
                end
            elseif strcmp(app.CUSelTE.SelectedObject.Tag,'Errp')
                if (strcmp(Selection,'ID') || strcmp(Selection,'DC')) && app.Prefs.TE.Check_DC == 1
                    app.Table_TEresults.Data = [app.Output.TE.dC.DC.f_DC'./app.Output.TE.C.DC.f_DC',app.Output.TE.dC.(Selection).Sample'./app.Output.TE.C.(Selection).Sample'];
                else
                    app.Table_TEresults.Data = app.Output.TE.dC.(Selection).Sample'./app.Output.TE.C.(Selection).Sample';
                end
            end
            setappdata(app.ICProUIFigure,'ActiveResultTE',Selection);

            if (strcmp(Selection,'ID')) && app.Prefs.TE.Check_DC == 1
                app.Table_TEresults.ColumnName = ['Dilution factor';app.Output.TE.IsotopesID];
            elseif (strcmp(Selection,'ID')) && app.Prefs.TE.Check_DC == 0
                app.Table_TEresults.ColumnName = app.Output.TE.IsotopesID;
            elseif strcmp(Selection,'DC')
                app.Table_TEresults.ColumnName = ['Dilution factor';app.Output.TE.Isotopes];
            else
                app.Table_TEresults.ColumnName = app.Output.TE.Isotopes;
            end
        end

        % Menu selected function: CopyThU
        function CopyResultsThU(app, event)
            % NUM2CLIP copies a numerical-array to the clipboard
            %
            %   ARRAYSTRING = NUM2CLIP(ARRAY)
            %
            %   Copies the numerical array ARRAY to the clipboard as a tab-separated
            %   string.  This format is suitable for direct pasting to Excel and other
            %   programs.
            %
            %   The tab-separated result is returned as ARRAYSTRING.  This
            %   functionality has been included for completeness.
            %
            % Author: Grigor Browning
            % Last update: 02-Sept-2005
            % convert the numerical array to a string array
            % note that num2str pads the output array with space characters to account
            %% for differing numbers of digits in each index entry

            array = app.Table_ThUresults.Data;
            arraystring = num2str(array);
            arraystring(:,end+1) = newline; %add a carrige return to the end of each row
            %reshape the array to a single line
            %note that the reshape function reshape is column based so to reshape by
            %rows one must use the inverse of the matrix
            arraystring = reshape(arraystring',1,numel(arraystring)); %reshape the array to a single line
            arraystringshift = [' ',arraystring]; %create a copy of arraystring shifted right by one space character
            arraystring = [arraystring,' ']; %add a space to the end of arraystring to make it the same length as arraystringshift
            %now remove the additional space charaters - keeping a single space
            %charater after each 'numerical' entry
            arraystring = arraystring((double(arraystring)~=32 | double(arraystringshift)~=32) & ~(double(arraystringshift==10) & double(arraystring)==32) );
            arraystring(double(arraystring)==32) = char(9); %convert the space characters to tab characters
            clipboard('copy',arraystring); %copy the result to the clipboard ready for pasting
        end

        % Menu selected function: CopyTE
        function CopyResultsTE(app, event)
            %NUM2CLIP copies a numerical-array to the clipboard
            %
            %   ARRAYSTRING = NUM2CLIP(ARRAY)
            %
            %   Copies the numerical array ARRAY to the clipboard as a tab-separated
            %   string.  This format is suitable for direct pasting to Excel and other
            %   programs.
            %
            %   The tab-separated result is returned as ARRAYSTRING.  This
            %   functionality has been included for completeness.
            %
            %Author: Grigor Browning
            %Last update: 02-Sept-2005
            %convert the numerical array to a string array
            %note that num2str pads the output array with space characters to account
            %for differing numbers of digits in each index entry
            array = app.Table_TEresults.Data;
            arraystring = num2str(array);
            arraystring(:,end+1) = newline; %add a carrige return to the end of each row
            %reshape the array to a single line
            %note that the reshape function reshape is column based so to reshape by
            %rows one must use the inverse of the matrix
            arraystring = reshape(arraystring',1,numel(arraystring)); %reshape the array to a single line
            arraystringshift = [' ',arraystring]; %create a copy of arraystring shifted right by one space character
            arraystring = [arraystring,' ']; %add a space to the end of arraystring to make it the same length as arraystringshift
            %now remove the additional space charaters - keeping a single space
            %charater after each 'numerical' entry
            arraystring = arraystring((double(arraystring)~=32 | double(arraystringshift)~=32) & ~(double(arraystringshift==10) & double(arraystring)==32) );
            arraystring(double(arraystring)==32) = char(9); %convert the space characters to tab characters
            clipboard('copy',arraystring); %copy the result to the clipboard ready for pasting
        end

        % Menu selected function: BCexport, DCexport, IDLRplots, 
        % IDexport, NatURatioExp, OCexport, QCexport, RCexport, 
        % Rawexport, SCexport, SamRatioExp, TEsheet, ThUIDExp, 
        % ThUsheet
        function Export(app, event)
            %---------------------------------------------------------------------------------
            % Callback: Export results
            % This function exports selected results as an excel spread sheet.
            %% -------------------------------------------------------------------------------

            % Determine analyses' toggle state
            State.TE = app.AnalysisSelection.SelectedObject == app.Toggle_TE;
            State.ThU = app.AnalysisSelection.SelectedObject == app.Toggle_ThU;

            % If available, get batch name for default file name (generic name in case of unsaved Batch
            if ~strcmp(app.BatchName,'unsaved batch')
                BatchControl(app,event)
                FileName = app.BatchName;
            else
                FileName = '[EnterBatchName]';
            end

            % File output directory, default: TE raw file directory,
            if State.ThU
                defaultpath = app.Input.AnalysisInfo.RawPath(1:max(strfind(app.Input.AnalysisInfo.RawPath,filesep)));
            elseif State.TE
                defaultpath = dir(app.Input.AnalysisInfo.RawPath).folder;
            end

            [FilePath1,FilePath2] = uiputfile('*.*','Select File',fullfile(defaultpath,FileName));
            if FilePath1 ~= 0
                if ~isfolder(fullfile(FilePath2,'ICPro Results'))
                    mkdir(fullfile(FilePath2,'ICPro Results'))
                end

                OutFile = fullfile(FilePath2,'ICPro Results',[FilePath1,'.xlsx']);
                OutFig = fullfile(FilePath2,'ICPro Results',[FilePath1,'.png']);
                if isfile(OutFile)
                    msg = 'Export file already exists and will be overwritten.';
                    title = 'Overwrite export file';
                    selection = questdlg(msg,title,'Save','Cancel','Save');
                    if strcmp(selection,'Save')
                        delete(OutFile)
                    end
                else
                    selection = 'Save';
                end

                % Export
                if strcmp(selection,'Save')
                    %                     % Info sheet
                    %                     Info = {'BATCH INFO SHEET';'';'GENERAL';'Processing date/time';'';'TE INFO';'Raw file location';'IDs (Sample/Blank/Spike/Rinse/QC)';'Corrections';'';'TH/U INFO';'Raw folder location';'# analysis blocks';'# samples/block';'IDs (Sample/proced. Blank/Blank/NatU)';'Sample sequence';'';'SPIKES';'229Th (ID/CY/RY)';'230Th (ID/CY/RY)';'236U (ID/CY/RYa/RYb)'};
                    %                     Info(:,2) = repmat({'NA'},21,1);
                    %                     Info([0,1,2,4,5,9,10,16,17]+1,2) = {''};
                    %                     % BatchName
                    %                     Info{1,2} = app.BatchName;
                    %                     % Date/time
                    %                     Info{4,2} = datestr(now,21);
                    %                     if (event.Source.Parent == app.TEIndRes || event.Source == app.TEsheet)
                    %                         % Raw file location
                    %                         Info{7,2} = app.Field_RawDataPath.Value;
                    %                         % TE IDs
                    %                         Info{8,2} = {' ',app.Field_SampleID.Value,app.Field_BlankID.Value,app.Field_SpikeID.Value,app.Field_RinseID.Value,app.Field_QCID.Value};
                    %                         % TE corrections
                    %                         YN = {'No','Yes'};
                    %                         Info{9,2} = {' ',['RC - ',YN{app.Prefs.TE.Check_OC+1}],[' QC - ',YN{app.Prefs.TE.Check_OC+1}],[' SC - ',YN{app.Prefs.TE.Check_OC+1}],[' OC - ',YN{app.Prefs.TE.Check_OC+1}],[' BC - ',YN{app.Prefs.TE.Check_BC+1}],[' DC - ',YN{app.Prefs.TE.Check_DC+1}]};
                    %                     end
                    %                     if event.Source.Parent == app.ThUIndRes || event.Source == app.ThUsheet
                    %                         % ThU raw data location
                    %                         Info{12,2} = app.Field_RawFolder.Value;
                    %                         % ThU # analysis blocks
                    %                         Info{13,2} = num2str(app.Field_NBlocks.Value);
                    %                         % ThU # samples per block
                    %                         Info{14,2} = num2str(app.Field_NSamples.Value);
                    %                         % ThU IDs
                    %                         Info{15,2} = {' ',app.Field_SampleID.Value,app.Field_BlankID.Value,app.Field_ICPBlankID.Value,app.Field_ICPNatUID.Value};
                    %                         % ThU sample sequence
                    %                         Info{16,2} = [{' '},app.Output.ThU.Sequence'];
                    %                         % Spike parameters
                    %                         Info{19,2} = {' ',app.DropDown_229.Items{app.DropDown_229.Value==app.DropDown_229.ItemsData},num2str(app.Field_CY229.Value),num2str(app.Field_RY229.Value)};
                    %                         Info{20,2} = {' ',app.DropDown_230.Items{app.DropDown_230.Value==app.DropDown_230.ItemsData},num2str(app.Field_CY230.Value),num2str(app.Field_RY230.Value)};
                    %                         Info{21,2} = {' ',app.DropDown_236.Items{app.DropDown_236.Value==app.DropDown_236.ItemsData},num2str(app.Field_CY236.Value),num2str(app.Field_RY236.Value),num2str(app.Field_RYb236.Value)};
                    %                     end
                    %
                    %                     if event.Source ~= app.IDLRplots
                    %                         OutTableInfo = array2table(Info);
                    %                         writetable(OutTableInfo,OutFile,'WriteVariableNames',0,'Sheet','INFO','WriteMode','overwritesheet')
                    %                     end

                    % TE result sheets, concentration & errors (individual)
                    if (event.Source.Parent == app.TEIndRes || event.Source == app.TEsheet) && event.Source ~= app.IDLRplots
                        gettag = event.Source.Tag;
                        for i = 1 : length(app.Output.TE.IsotopesID)
                            app.Output.TE.IsotopesIDout{i} = [app.Output.TE.IsotopesID{i},' [ppm]'];
                            app.Output.TE.Isotopesout{i} = [app.Output.TE.Isotopes{i},' [ppm]'];
                        end
                        if strcmp(gettag,'ID') || event.Source == app.TEsheet
                            OutTableC = array2table(app.Output.TE.C.ID.Sample','RowNames',app.Output.TE.RunID.Sample,'VariableNames',app.Output.TE.IsotopesIDout);
                            OutTableE = array2table(app.Output.TE.dC.ID.Sample','RowNames',app.Output.TE.RunID.Sample,'VariableNames',app.Output.TE.IsotopesIDout);
                            OutTableC.Properties.DimensionNames{1} = 'Sample ID';
                            OutTableE.Properties.DimensionNames{1} = 'Sample ID';
                            writetable(OutTableC,OutFile,'Sheet','{TE} Concentrations (ID) [ug/g]','WriteMode','overwritesheet','WriteRowNames',true)
                            writetable(OutTableE,OutFile,'Sheet','{TE} Errors (ID) [ug/g]','WriteMode','overwritesheet','WriteRowNames',true)
                        else
                            OutTableC = array2table(app.Output.TE.C.(gettag).Sample','RowNames',app.Output.TE.RunID.Sample,'VariableNames',app.Output.TE.Isotopesout);
                            OutTableE = array2table(app.Output.TE.dC.(gettag).Sample','RowNames',app.Output.TE.RunID.Sample,'VariableNames',app.Output.TE.Isotopesout);
                            OutTableC.Properties.DimensionNames{1} = 'Sample ID';
                            OutTableE.Properties.DimensionNames{1} = 'Sample ID';
                            writetable(OutTableC,OutFile,'Sheet',['{TE} Concentrations (',gettag,') [ug/g]'],'WriteMode','overwritesheet','WriteRowNames',true)
                            writetable(OutTableE,OutFile,'Sheet',['{TE} Errors (',gettag,') [ug/g]'],'WriteMode','overwritesheet','WriteRowNames',true)
                        end
                    end

                    % ID vs LR plots
                    if event.Source == app.IDLRplots
                        fig = figure('Visible','off');
                        fig.Position = [0 0 1000 500];
                        h1 = copyobj(app.ThIDFig,fig);
                        set(h1,'ActivePositionProperty','outerposition')
                        set(h1,'Units','normalized')
                        set(h1,'OuterPosition',[0 0 1 1])
                        set(h1,'Position',[0.025 0.05 .45 .9])
                        set(h1,'Visible','on')
                        for iC = 1 : length(h1.Children)
                            h1.Children(iC).Visible = 'on';
                        end
                        h2 = copyobj(app.UIDFig,fig);
                        set(h2,'ActivePositionProperty','outerposition')
                        set(h2,'Units','normalized')
                        set(h2,'OuterPosition',[0 0 1 1])
                        set(h2,'Position',[0.525 0.05 .45 .9])
                        set(h2,'Visible','on')
                        for iC = 1 : length(h2.Children)
                            h2.Children(iC).Visible = 'on';
                        end
                        saveas(fig,OutFig)
                        close(fig)
                    end

                    % Th/U result sheets, concentration & errors (individual)
                    if event.Source.Parent == app.ThUIndRes || event.Source == app.ThUsheet
                        ThUSampleInd = find(~cellfun(@isempty,app.Output.ThU.SampleSeq(1:end)));
                        if event.Source == app.SamRatioExp || event.Source == app.ThUsheet
                            OutTableCa = array2table([app.Output.ThU.Ratios.Sample.R229_230_cMB(ThUSampleInd)',app.Output.ThU.Ratios.Sample.R236_234_cMB(ThUSampleInd)'],'RowNames',app.Output.ThU.SampleSeq(ThUSampleInd),'VariableNames',{'Th229/Th230','U236/U234'});
                            OutTableEa = array2table([app.Output.ThU.dRatios.Sample.R229_230_cMB(ThUSampleInd)',app.Output.ThU.dRatios.Sample.R236_234_cMB(ThUSampleInd)'],'RowNames',app.Output.ThU.SampleSeq(ThUSampleInd),'VariableNames',{'Th229/Th230','U236/U234'});
                            OutTableCa.Properties.DimensionNames{1} = 'Sample ID';
                            OutTableEa.Properties.DimensionNames{1} = 'Sample ID';
                        end
                        if event.Source == app.NatURatioExp || event.Source == app.ThUsheet
                            OutTableCb = array2table(app.Output.ThU.Ratios.NatU.R235_234_cMB,'RowNames',app.Output.ThU.NatUSeq,'VariableNames',{'U235/U234'});
                            OutTableEb = array2table(app.Output.ThU.dRatios.NatU.R235_234_cMB,'RowNames',app.Output.ThU.NatUSeq,'VariableNames',{'U235/U234'});
                            OutTableCb.Properties.DimensionNames{1} = 'Sample ID';
                            OutTableEb.Properties.DimensionNames{1} = 'Sample ID';
                        end
                        if event.Source == app.ThUIDExp || event.Source == app.ThUsheet
                            if ~isempty(app.Field_BlankID.Value)
                                OutTableCc = array2table([app.Output.ThU.ID.Th230BC,app.Output.ThU.ID.U234BC],'RowNames',app.Output.ThU.SampleSeq(ThUSampleInd),'VariableNames',{'Th230 (ID), blank corrected [pg/g]','U234 (ID), blank corrected [pg/g]'});
                                OutTableEc = array2table([app.Output.ThU.dID.Th230BC,app.Output.ThU.dID.U234BC],'RowNames',app.Output.ThU.SampleSeq(ThUSampleInd),'VariableNames',{'Th230 (ID), blank corrected [pg/g]','U234 (ID), blank corrected [pg/g]'});
                            else
                                OutTableCc = array2table([app.Output.ThU.ID.Th230,app.Output.ThU.ID.U234],'RowNames',app.Output.ThU.SampleSeq(ThUSampleInd),'VariableNames',{'Th230 (ID) [pg/g]','U234 (ID) [pg/g]'});
                                OutTableEc = array2table([app.Output.ThU.dID.Th230,app.Output.ThU.dID.U234],'RowNames',app.Output.ThU.SampleSeq(ThUSampleInd),'VariableNames',{'Th230 (ID) [pg/g]','U234 (ID) [pg/g]'});
                            end
                            OutTableCc.Properties.DimensionNames{1} = 'Sample ID';
                            OutTableEc.Properties.DimensionNames{1} = 'Sample ID';
                        end

                        if event.Source == app.ThUsheet
                            writetable(OutTableCa,OutFile,'Sheet','{Th & U} Ratios & Concentr.','WriteMode','inplace','WriteRowNames',true,'Range','A1')
                            writetable(OutTableCb,OutFile,'Sheet','{Th & U} Ratios & Concentr.','WriteMode','inplace','WriteRowNames',true,'Range','G1')
                            writetable(OutTableCc,OutFile,'Sheet','{Th & U} Ratios & Concentr.','WriteMode','inplace','WriteRowNames',false,'Range','D1')
                            writetable(OutTableEa,OutFile,'Sheet','{Th & U} Errors','WriteMode','inplace','WriteRowNames',true,'Range','A1')
                            writetable(OutTableEb,OutFile,'Sheet','{Th & U} Errors','WriteMode','inplace','WriteRowNames',true,'Range','G1')
                            writetable(OutTableEc,OutFile,'Sheet','{Th & U} Errors','WriteMode','inplace','WriteRowNames',false,'Range','D1')
                        elseif event.Source == app.SamRatioExp
                            writetable(OutTableCa,OutFile,'Sheet','{Th & U} Ratios & Concentr.','WriteMode','overwritesheet','WriteRowNames',true,'Range','A1')
                            writetable(OutTableEa,OutFile,'Sheet','{Th & U} Errors','WriteMode','overwritesheet','WriteRowNames',true,'Range','A1')
                        elseif event.Source == app.NatURatioExp
                            writetable(OutTableCb,OutFile,'Sheet','{Th & U} Ratios & Concentr.','WriteMode','overwritesheet','WriteRowNames',true,'Range','A1')
                            writetable(OutTableEb,OutFile,'Sheet','{Th & U} Errors','WriteMode','overwritesheet','WriteRowNames',true,'Range','A1')
                        elseif event.Source == app.ThUIDExp
                            writetable(OutTableCc,OutFile,'Sheet','{Th & U} Ratios & Concentr.','WriteMode','overwritesheet','WriteRowNames',true,'Range','A1')
                            writetable(OutTableEc,OutFile,'Sheet','{Th & U} Errors','WriteMode','overwritesheet','WriteRowNames',true,'Range','A1')
                        end
                    end

                    % Spike recovery plot
                    %                     if event.Source == app.IDLRplots
                    %                         fig = figure('Visible','off');
                    %                         fig.Position = [0 0 1000 500];
                    %                         h1 = copyobj(app.RecoveryFig,fig);
                    %                         set(h1,'ActivePositionProperty','outerposition')
                    %                         set(h1,'Units','normalized')
                    %                         set(h1,'OuterPosition',[0 0 1 1])
                    %                         set(h1,'Position',[0.025 0.05 .45 .9])
                    %                         set(h1,'Visible','on')
                    %                         for iC = 1 : length(h1.Children)
                    %                             h1.Children(iC).Visible = 'on';
                    %                         end
                    %                         saveas(fig,OutFig)
                    %                         close(fig)
                    %                     end
                end
            end

            % Bring ICPro to foreground
            figure(app.ICProUIFigure)
        end

        % Menu selected function: ClearBatchMenu, DeleteBatchMenu, 
        % LoadBatchMenu, SaveAsBatchMenu, SaveBatchMenu
        function BatchControl(app, event)
            %---------------------------------------------------------------------------------
            % Callback: Batch control
            % This function collectively manages the handling of batch files:
            % - Saving input parameters to a new/existing batch file
            % - Importing parameters from a saved batch file
            % - Deleting batch files
            % - Clearing current input
            % Prior to clearing the input and importing a batch the user will be notified of
            % unsaved changes.
            %% -------------------------------------------------------------------------------

            % Get list of all batch files
            AllBatchFiles = dir(fullfile(app.AppPath.folder,'misc','Batch Input Files','*.mat'));
            BFList = cell(length(AllBatchFiles),1);
            for iF = 1 : length(AllBatchFiles)
                if ~contains(AllBatchFiles(iF).name,'~') || ~contains(AllBatchFiles(iF).name,'._')
                    BFList{iF} = AllBatchFiles(iF).name;
                end
            end
            BFList(contains(BFList,'~')) = [];

            % CASE 1: Load previously saved batch from batch file
            if event.Source == app.LoadBatchMenu
                msg = 'Unsaved changes to will be discarded. Load new batch and discard changes?';
                button = 'Proceed';
                discard = CheckForChanges(app,msg,button);
                if true(discard)
                    % Select batch file
                    if ~isempty(BFList)
                        indx = listdlg('SelectionMode','single','ListString',BFList,'ListSize',[160 100]);
                        if ~isempty(indx)
                            % Get batch file name from selection and load input parameters
                            BatchFile = BFList{indx};
                            load(fullfile(app.AppPath.folder,'misc','Batch Input Files',BatchFile),'IN');

                            % Save batch name to app property 'BatchName' and rename app window accordingly
                            app.BatchName = BatchFile(1:end-4);
                            app.ICProUIFigure.Name = ['ICPro - ',app.BatchName];
                            % Run function 'setInput'
                            setInput(app,event,IN)
                        end
                    else
                        warndlg('No batch files found.')
                    end
                end
            end

            % CASE 2: Delete previously saved batch file
            if event.Source == app.DeleteBatchMenu
                if ~isempty(BFList)
                    % Select batch file
                    indx = listdlg('SelectionMode','single','ListString',BFList,'ListSize',[160 100]);
                    if ~isempty(indx)
                        % Get batch file name from selection
                        BatchFile = BFList{indx};
                        % Delete confirmation prompt
                        msg = ['Do you want to delete batch input file ''',BatchFile(1:end-4),'''?'];
                        title = 'Delete batch file';
                        selection = questdlg(msg,title,'Delete','Cancel','Delete');
                        if strcmp(selection,'Delete') % skip if selection was cancelled
                            % Delete file
                            delete(fullfile(app.AppPath.folder,'misc','Batch Input Files',BatchFile));
                            if strcmp(app.BatchName,BatchFile(1:end-4))
                                % Rename app window to 'unsaved batch'
                                app.ICProUIFigure.Name = 'ICPro - unnamed batch';
                                % Run function 'resetInput'
                                resetInput(app,event)
                            end
                        end
                    end
                else
                    warndlg('No batch files found.')
                end
            end

            % CASE 3: Clear current input and reset input window
            if event.Source == app.ClearBatchMenu
                msg = 'Unsaved changes will be discarded. Clear window and discard changes?';
                button = 'Proceed';
                discard = CheckForChanges(app,msg,button);
                if true(discard)
                    % Rename app window to 'unsaved batch'
                    app.ICProUIFigure.Name = 'ICPro - unnamed batch';
                    % Run function 'resetInput'
                    resetInput(app,event)
                end
            end

            % CASE 4: Save as new file (in case of 'Save as...' OR unsaved batch)
            unsaved = event.Source == app.SaveBatchMenu && strcmp(app.ICProUIFigure.Name,'ICPro - unnamed batch');
            if event.Source == app.SaveAsBatchMenu || unsaved
                % Get all input data, parameters and selections
                IN = getInput(app);

                IN.BatchName = inputdlg('Specify batch name:');
                if ~isempty(IN.BatchName)
                    app.BatchName = IN.BatchName{1};
                    BatchExist = zeros(numel(AllBatchFiles),1);
                    for iB = 1 : numel(AllBatchFiles)
                        BatchExist(iB) =  strcmp([IN.BatchName{1},'.mat'],AllBatchFiles(iB).name);
                    end
                    if sum(BatchExist) == 1
                        msg = 'Batch file already exists and will be overwritten.';
                        title = 'Overwrite batch file';
                        selection = questdlg(msg,title,'Save','Cancel','Save');
                        if strcmp(selection,'Save')
                            save(fullfile(app.AppPath.folder,'misc','Batch Input Files',[IN.BatchName{1},'.mat']),'IN');
                            app.ICProUIFigure.Name = ['ICPro - ',app.BatchName];
                        end
                    else
                        save(fullfile(app.AppPath.folder,'misc','Batch Input Files',[IN.BatchName{1},'.mat']),'IN');
                        app.ICProUIFigure.Name = ['ICPro - ',app.BatchName];
                    end
                end
                clearvars('IN')
            end

            % CASE 5: Save to previously saved batch file (in case of 'Save' AND previously saved batch file)
            presaved = event.Source == app.SaveBatchMenu && ~strcmp(app.ICProUIFigure.Name,'ICPro - unnamed batch');
            export = (event.Source.Parent == app.ExportMenu || event.Source.Parent == app.TEIndRes || event.Source.Parent == app.ThUIndRes) && ~strcmp(app.ICProUIFigure.Name,'ICPro - unnamed batch');
            if presaved || export
                % Get all input data, parameters and selections
                IN = getInput(app);
                save(fullfile(app.AppPath.folder,'misc','Batch Input Files',[IN.BatchName{1},'.mat']),'IN');
                app.ICProUIFigure.Name = ['ICPro - ',app.BatchName];
                clearvars('IN')
            end
        end

        % Callback function: Button_Preferences, PreferencesMenu
        function Preferences(app, event)
            ICProPreferences
        end

        % Menu selected function: AboutICProMenu, GuideMenu
        function ShowInfo(app, event)
            %---------------------------------------------------------------------------------
            % Callback: Show ICPro info and guide
            % This function opens an info window or a user guide.
            %% -------------------------------------------------------------------------------

            if ispc
                fontsizecorr = .8;
                fontweight = 'normal';
                fontname = 'Arial';
            else
                fontsizecorr = 1;
                fontweight = 'bold';
                fontname = 'Avenir';
            end
            
            if event.Source == app.AboutICProMenu
                d = dialog('Position',[500 500 250 350],'Name','About ICPro');

                axes('Parent',d,...
                    'Units','normalized',...
                    'Position',[.1 .6 .8 .3],...
                    'Position',[0 .55 1 .5],...
                    'XColor','none',...
                    'YColor','none');
                [icon,~,alpha] = imread(fullfile(app.AppPath.folder,'misc','icons','appicon_square.png'));
                f = imshow(icon(:,:,1:3),'InitialMagnification',20);
                set(f, 'AlphaData', alpha);

                uicontrol('Parent',d,...
                    'Style','text',...
                    'Units','normalized',...
                    'Position',[.1 .3 .8 .2],...
                    'FontName',fontname,...
                    'FontWeight',fontweight,...
                    'FontSize',24*fontsizecorr,...
                    'String',['ICPro ',app.Version(1:end)]);

                uicontrol('Parent',d,...
                    'Style','text',...
                    'Units','normalized',...
                    'Position',[.1 .15 .8 .25],...
                    'FontName',fontname,...
                    'FontWeight',fontweight,...
                    'FontSize',14*fontsizecorr,...
                    'String',{[char(hex2dec('A9')),' Jake Weis, ',datestr(date,3),' ',datestr(date,10)];' ';'https://github.com/JakeWeis';'jakob.weis@utas.edu.au'});

                waitfor(d)
                % Bring ICPro to foreground
                figure(app.ICProUIFigure)
            elseif event.Source == app.GuideMenu
                open(fullfile(app.AppPath.folder,'misc','info','ICPro - User Guide.pdf'))
            elseif event.Source == app.TestBatchMenu
                BatchControl(app,event)
            end

        end

        % Close request function: ICProUIFigure
        function CloseApp(app, event)
            %---------------------------------------------------------------------------------
            % Callback: Quitting ICPro
            % This function quits ICPro. Prior to quitting the user will be notified of
            % unsaved changes.
            %% -------------------------------------------------------------------------------

            msg = 'Unsaved changes will be discarded. Quit and discard changes?';
            button = 'Quit';
            discard = CheckForChanges(app,msg,button);
            if true(discard)
                delete(app)
            else
                % Bring ICPro to foreground
                figure(app.ICProUIFigure)
            end
        end

        % Value changed function: Field_RawDataPath
        function AutoToggle(app, event)
            value = app.Field_RawDataPath.Value;

            if isfolder(value)
                app.AnalysisSelection.SelectedObject = app.Toggle_ThU;
                AnalysisToggle(app,event)
            elseif isfile(value)
                app.AnalysisSelection.SelectedObject = app.Toggle_TE;
                AnalysisToggle(app,event)
            end
        end

        % Menu selected function: CheckforUpdatesMenu
        function UpdateICPro(app, event)
            newestVersion = githubFetch('JakeWeis','ICPro','version','latest');

            if strcmp(app.Version,newestVersion)
                if ~strcmp(event,'startup')
                    msgbox(['ICPro is up to date (v',app.Version,').'],'Up to date');
                end
            else
                title = 'Update available';
                msg = ['A newer version of ICPro is available (v',newestVersion,')'];
                selection = questdlg(msg,title,'Update','Remind me later','Update');

                if strcmp(selection,'Update')
                    AppParentDir = strsplit(app.AppPath.folder,filesep);
                    AppParentDir = fullfile(AppParentDir{1:end-1});
                    cd(AppParentDir)
                    w = waitbar(0,'Downloading');
                    NewVersion = githubFetch('JakeWeis','ICPro','release','latest');
                    unzip(NewVersion,dir(NewVersion).folder)
                    delete(NewVersion)
                    waitbar(1,w,'Done');
                    cd(app.AppPath.folder)
                    t = timer('ExecutionMode','singleShot','StartDelay',1,'TimerFcn',@(~,~)close(w));
                    start(t)
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ICProUIFigure = uifigure('Visible', 'off');
            app.ICProUIFigure.Name = 'ICPro - unnamed batch';
            app.ICProUIFigure.Icon = 'appicon_square.png';
            app.ICProUIFigure.CloseRequestFcn = createCallbackFcn(app, @CloseApp, true);
            app.ICProMenu = uimenu(app.ICProUIFigure);
            app.ICProMenu.Text = 'ICPro';
            app.AboutICProMenu = uimenu(app.ICProMenu);
            app.AboutICProMenu.MenuSelectedFcn = createCallbackFcn(app, @ShowInfo, true);
            app.AboutICProMenu.Text = 'About ICPro';
            app.CheckforUpdatesMenu = uimenu(app.ICProMenu);
            app.CheckforUpdatesMenu.MenuSelectedFcn = createCallbackFcn(app, @UpdateICPro, true);
            app.CheckforUpdatesMenu.Text = 'Check for Updates...';
            app.PreferencesMenu = uimenu(app.ICProMenu);
            app.PreferencesMenu.MenuSelectedFcn = createCallbackFcn(app, @Preferences, true);
            app.PreferencesMenu.Accelerator = 'P';
            app.PreferencesMenu.Text = 'Preferences';
            app.GuideMenu = uimenu(app.ICProMenu);
            app.GuideMenu.MenuSelectedFcn = createCallbackFcn(app, @ShowInfo, true);
            app.GuideMenu.Accelerator = 'G';
            app.GuideMenu.Text = 'Guide';
            app.ExportMenu = uimenu(app.ICProUIFigure);
            app.ExportMenu.Tooltip = {'Export options'};
            app.ExportMenu.Visible = 'off';
            app.ExportMenu.Enable = 'off';
            app.ExportMenu.Text = 'Export';
            app.IndResExp = uimenu(app.ExportMenu);
            app.IndResExp.Tooltip = {''};
            app.IndResExp.Enable = 'off';
            app.IndResExp.Text = 'Individual results';
            app.TEIndRes = uimenu(app.IndResExp);
            app.TEIndRes.Tooltip = {'Exports concentrations and uncertainties'};
            app.TEIndRes.Enable = 'off';
            app.TEIndRes.Text = 'Trace elements';
            app.IDLRplots = uimenu(app.TEIndRes);
            app.IDLRplots.MenuSelectedFcn = createCallbackFcn(app, @Export, true);
            app.IDLRplots.Enable = 'off';
            app.IDLRplots.Text = 'ID vs LR plots';
            app.IDexport = uimenu(app.TEIndRes);
            app.IDexport.MenuSelectedFcn = createCallbackFcn(app, @Export, true);
            app.IDexport.Enable = 'off';
            app.IDexport.Text = 'Isotopic dilution';
            app.IDexport.Tag = 'ID';
            app.DCexport = uimenu(app.TEIndRes);
            app.DCexport.MenuSelectedFcn = createCallbackFcn(app, @Export, true);
            app.DCexport.Enable = 'off';
            app.DCexport.Text = 'Dilution corrected';
            app.DCexport.Tag = 'DC';
            app.BCexport = uimenu(app.TEIndRes);
            app.BCexport.MenuSelectedFcn = createCallbackFcn(app, @Export, true);
            app.BCexport.Enable = 'off';
            app.BCexport.Text = 'Blank corrected';
            app.BCexport.Tag = 'BC';
            app.OCexport = uimenu(app.TEIndRes);
            app.OCexport.MenuSelectedFcn = createCallbackFcn(app, @Export, true);
            app.OCexport.Enable = 'off';
            app.OCexport.Text = 'Oxide corrected';
            app.OCexport.Tag = 'OC';
            app.SCexport = uimenu(app.TEIndRes);
            app.SCexport.MenuSelectedFcn = createCallbackFcn(app, @Export, true);
            app.SCexport.Enable = 'off';
            app.SCexport.Text = 'Spike corrected';
            app.SCexport.Tag = 'SC';
            app.QCexport = uimenu(app.TEIndRes);
            app.QCexport.MenuSelectedFcn = createCallbackFcn(app, @Export, true);
            app.QCexport.Enable = 'off';
            app.QCexport.Text = 'QC corrected';
            app.QCexport.Tag = 'QC';
            app.RCexport = uimenu(app.TEIndRes);
            app.RCexport.MenuSelectedFcn = createCallbackFcn(app, @Export, true);
            app.RCexport.Enable = 'off';
            app.RCexport.Text = 'Rinse corrected';
            app.RCexport.Tag = 'RC';
            app.Rawexport = uimenu(app.TEIndRes);
            app.Rawexport.MenuSelectedFcn = createCallbackFcn(app, @Export, true);
            app.Rawexport.Enable = 'off';
            app.Rawexport.Text = 'Raw data';
            app.Rawexport.Tag = 'Raw';
            app.ThUIndRes = uimenu(app.IndResExp);
            app.ThUIndRes.Tooltip = {'Exports ratios and uncertainties'};
            app.ThUIndRes.Enable = 'off';
            app.ThUIndRes.Text = 'Th/U';
            app.ThUIDExp = uimenu(app.ThUIndRes);
            app.ThUIDExp.MenuSelectedFcn = createCallbackFcn(app, @Export, true);
            app.ThUIDExp.Tooltip = {'Th230 & U234'};
            app.ThUIDExp.Enable = 'off';
            app.ThUIDExp.Text = 'Isotopic Dilution';
            app.SamRatioExp = uimenu(app.ThUIndRes);
            app.SamRatioExp.MenuSelectedFcn = createCallbackFcn(app, @Export, true);
            app.SamRatioExp.Tooltip = {'Th230/Th229'; 'U236/U234'};
            app.SamRatioExp.Enable = 'off';
            app.SamRatioExp.Text = 'Sample ratios';
            app.NatURatioExp = uimenu(app.ThUIndRes);
            app.NatURatioExp.MenuSelectedFcn = createCallbackFcn(app, @Export, true);
            app.NatURatioExp.Tooltip = {'U235/U234'};
            app.NatURatioExp.Enable = 'off';
            app.NatURatioExp.Text = 'NatU ratio';
            app.TEsheet = uimenu(app.ExportMenu);
            app.TEsheet.MenuSelectedFcn = createCallbackFcn(app, @Export, true);
            app.TEsheet.Tooltip = {'- TE concentrations and uncertainties (with isotopic dilution)'; '- ID vs LR plots (Th232, U238)'};
            app.TEsheet.Enable = 'off';
            app.TEsheet.Accelerator = '1';
            app.TEsheet.Text = 'TE result sheet';
            app.ThUsheet = uimenu(app.ExportMenu);
            app.ThUsheet.MenuSelectedFcn = createCallbackFcn(app, @Export, true);
            app.ThUsheet.Tooltip = {'- Mass bias corrected sample ratios and uncertainties (Th229/Th230, U236/U234)'; '- Mass bias corrected NatU ratio and uncertainties (U236/U234)'};
            app.ThUsheet.Enable = 'off';
            app.ThUsheet.Accelerator = '2';
            app.ThUsheet.Text = 'Th/U result sheet';
            app.BatchMenu = uimenu(app.ICProUIFigure);
            app.BatchMenu.Text = 'Batch';
            app.SaveBatchMenu = uimenu(app.BatchMenu);
            app.SaveBatchMenu.MenuSelectedFcn = createCallbackFcn(app, @BatchControl, true);
            app.SaveBatchMenu.Tooltip = {'Saves the current workspace to a batch file, which can later be imported.'};
            app.SaveBatchMenu.Accelerator = 'S';
            app.SaveBatchMenu.Text = 'Save';
            app.SaveBatchMenu.Tag = 'SaveBatch';
            app.SaveAsBatchMenu = uimenu(app.BatchMenu);
            app.SaveAsBatchMenu.MenuSelectedFcn = createCallbackFcn(app, @BatchControl, true);
            app.SaveAsBatchMenu.Accelerator = 'N';
            app.SaveAsBatchMenu.Text = 'Save as...';
            app.SaveAsBatchMenu.Tag = 'SaveBatch';
            app.LoadBatchMenu = uimenu(app.BatchMenu);
            app.LoadBatchMenu.MenuSelectedFcn = createCallbackFcn(app, @BatchControl, true);
            app.LoadBatchMenu.Tooltip = {'Loads a previously saved Batch.'};
            app.LoadBatchMenu.Accelerator = 'I';
            app.LoadBatchMenu.Text = 'Import';
            app.DeleteBatchMenu = uimenu(app.BatchMenu);
            app.DeleteBatchMenu.MenuSelectedFcn = createCallbackFcn(app, @BatchControl, true);
            app.DeleteBatchMenu.Tooltip = {'Deletes a previously saved batch file.'};
            app.DeleteBatchMenu.Accelerator = 'D';
            app.DeleteBatchMenu.Text = 'Delete';
            app.ClearBatchMenu = uimenu(app.BatchMenu);
            app.ClearBatchMenu.MenuSelectedFcn = createCallbackFcn(app, @BatchControl, true);
            app.ClearBatchMenu.Tooltip = {'Clears the current workspace and resets it to initial settings.'};
            app.ClearBatchMenu.Accelerator = 'X';
            app.ClearBatchMenu.Text = 'Clear';
            app.Panel_Spikes = uipanel(app.ICProUIFigure);
            app.Panel_Spikes.Title = 'Spikes';
            app.ThLabel = uilabel(app.Panel_Spikes);
            app.ThLabel.Text = '229Th';
            app.DropDown_229 = uidropdown(app.Panel_Spikes);
            app.DropDown_229.Items = {'Select'};
            app.DropDown_229.ItemsData = 0;
            app.DropDown_229.DropDownOpeningFcn = createCallbackFcn(app, @Spikes, true);
            app.DropDown_229.ValueChangedFcn = createCallbackFcn(app, @Spikes, true);
            app.DropDown_229.Tag = 'get229';
            app.DropDown_229.Value = 0;
            app.Field_CY229 = uieditfield(app.Panel_Spikes, 'numeric');
            app.Field_CY229.ValueDisplayFormat = '%.2f';
            app.Field_CY229.Tag = 'Field_229ThCY';
            app.Field_CY229.Editable = 'off';
            app.Field_CY229.Tooltip = {'229Th concentration in spike (pg/g)'};
            app.ConcentrationLabel = uilabel(app.Panel_Spikes);
            app.ConcentrationLabel.Text = 'Concentration';
            app.IsotopeamtratioTh229230Label = uilabel(app.Panel_Spikes);
            app.IsotopeamtratioTh229230Label.Text = 'Isotope amt. ratio Th229/230';
            app.Field_RY229 = uieditfield(app.Panel_Spikes, 'numeric');
            app.Field_RY229.ValueDisplayFormat = '%.2f';
            app.Field_RY229.Tag = 'Field_229ThRY';
            app.Field_RY229.Editable = 'off';
            app.Field_RY229.Tooltip = {'Th229/230 ratio in spike (if unknown assume infinite, Inf)'};
            app.Field_RY229.Value = Inf;
            app.Button_EdtSve229 = uibutton(app.Panel_Spikes, 'push');
            app.Button_EdtSve229.ButtonPushedFcn = createCallbackFcn(app, @Spikes, true);
            app.Button_EdtSve229.Tag = 'edt229';
            app.Button_EdtSve229.Enable = 'off';
            app.Button_EdtSve229.Tooltip = {'Edit and save parameters of selected spike.'};
            app.Button_EdtSve229.Text = 'edit';
            app.Button_Delete229 = uibutton(app.Panel_Spikes, 'push');
            app.Button_Delete229.ButtonPushedFcn = createCallbackFcn(app, @Spikes, true);
            app.Button_Delete229.Tag = 'dlt229';
            app.Button_Delete229.Enable = 'off';
            app.Button_Delete229.Tooltip = {'Delete selected spike from list of preset spikes.'};
            app.Button_Delete229.Text = 'delete';
            app.ThLabel_2 = uilabel(app.Panel_Spikes);
            app.ThLabel_2.Text = '230Th';
            app.DropDown_230 = uidropdown(app.Panel_Spikes);
            app.DropDown_230.Items = {'Select'};
            app.DropDown_230.ItemsData = 0;
            app.DropDown_230.DropDownOpeningFcn = createCallbackFcn(app, @Spikes, true);
            app.DropDown_230.ValueChangedFcn = createCallbackFcn(app, @Spikes, true);
            app.DropDown_230.Tag = 'get230';
            app.DropDown_230.Enable = 'off';
            app.DropDown_230.Value = 0;
            app.Button_EdtSve230 = uibutton(app.Panel_Spikes, 'push');
            app.Button_EdtSve230.ButtonPushedFcn = createCallbackFcn(app, @Spikes, true);
            app.Button_EdtSve230.Tag = 'edt230';
            app.Button_EdtSve230.Enable = 'off';
            app.Button_EdtSve230.Tooltip = {'Edit and save parameters of selected spike.'};
            app.Button_EdtSve230.Text = 'edit';
            app.Button_Delete230 = uibutton(app.Panel_Spikes, 'push');
            app.Button_Delete230.ButtonPushedFcn = createCallbackFcn(app, @Spikes, true);
            app.Button_Delete230.Tag = 'dlt230';
            app.Button_Delete230.Enable = 'off';
            app.Button_Delete230.Tooltip = {'Delete selected spike from list of preset spikes.'};
            app.Button_Delete230.Text = 'delete';
            app.ConcentrationLabel_2 = uilabel(app.Panel_Spikes);
            app.ConcentrationLabel_2.Text = 'Concentration';
            app.IsotopeamtratioTh230229Label = uilabel(app.Panel_Spikes);
            app.IsotopeamtratioTh230229Label.Text = 'Isotope amt. ratio Th230/229';
            app.Field_CY230 = uieditfield(app.Panel_Spikes, 'numeric');
            app.Field_CY230.ValueDisplayFormat = '%.2f';
            app.Field_CY230.Tag = 'Field_230ThCY';
            app.Field_CY230.Editable = 'off';
            app.Field_CY230.Enable = 'off';
            app.Field_CY230.Tooltip = {'230Th concentration in spike (pg/g)'};
            app.ULabel = uilabel(app.Panel_Spikes);
            app.ULabel.Text = '236U';
            app.DropDown_236 = uidropdown(app.Panel_Spikes);
            app.DropDown_236.Items = {'Select'};
            app.DropDown_236.ItemsData = 0;
            app.DropDown_236.DropDownOpeningFcn = createCallbackFcn(app, @Spikes, true);
            app.DropDown_236.ValueChangedFcn = createCallbackFcn(app, @Spikes, true);
            app.DropDown_236.Tag = 'get236';
            app.DropDown_236.Value = 0;
            app.Button_EdtSve236 = uibutton(app.Panel_Spikes, 'push');
            app.Button_EdtSve236.ButtonPushedFcn = createCallbackFcn(app, @Spikes, true);
            app.Button_EdtSve236.Tag = 'edt236';
            app.Button_EdtSve236.Enable = 'off';
            app.Button_EdtSve236.Tooltip = {'Edit and save parameters of selected spike.'};
            app.Button_EdtSve236.Text = 'edit';
            app.Button_Delete236 = uibutton(app.Panel_Spikes, 'push');
            app.Button_Delete236.ButtonPushedFcn = createCallbackFcn(app, @Spikes, true);
            app.Button_Delete236.Tag = 'dlt236';
            app.Button_Delete236.Enable = 'off';
            app.Button_Delete236.Tooltip = {'Delete selected spike from list of preset spikes.'};
            app.Button_Delete236.Text = 'delete';
            app.Field_CY236 = uieditfield(app.Panel_Spikes, 'numeric');
            app.Field_CY236.ValueDisplayFormat = '%.2f';
            app.Field_CY236.Tag = 'Field_236UCY';
            app.Field_CY236.Editable = 'off';
            app.Field_CY236.Tooltip = {'U236 concentration in spike (pg/g)'};
            app.ConcentrationLabel_3 = uilabel(app.Panel_Spikes);
            app.ConcentrationLabel_3.Text = 'Concentration';
            app.IsotopeamtratioU236238Label = uilabel(app.Panel_Spikes);
            app.IsotopeamtratioU236238Label.Text = 'Isotope amt. ratio U236/238';
            app.IsotopeamtratioU236234Label = uilabel(app.Panel_Spikes);
            app.IsotopeamtratioU236234Label.Text = 'Isotope amt. ratio U236/234';
            app.Field_RY236 = uieditfield(app.Panel_Spikes, 'numeric');
            app.Field_RY236.ValueDisplayFormat = '%.2f';
            app.Field_RY236.Tag = '230';
            app.Field_RY236.Editable = 'off';
            app.Field_RY236.Enable = 'off';
            app.Field_RY236.Tooltip = {'U236/238 ratio in spike for isotopic dilution calculations of U238 (if unknown assume infinite, Inf)'};
            app.Field_RY236.Value = Inf;
            app.Field_RYb236 = uieditfield(app.Panel_Spikes, 'numeric');
            app.Field_RYb236.ValueDisplayFormat = '%.2f';
            app.Field_RYb236.Tag = '229';
            app.Field_RYb236.Editable = 'off';
            app.Field_RYb236.Tooltip = {'Th236/234 ratio in spike for isotopic dilution calculations of U234 (if unknown assume infinite, Inf)'};
            app.Field_RYb236.Value = Inf;
            app.Field_RY230 = uieditfield(app.Panel_Spikes, 'numeric');
            app.Field_RY230.ValueDisplayFormat = '%.2f';
            app.Field_RY230.Tag = 'Field_230ThRY';
            app.Field_RY230.Editable = 'off';
            app.Field_RY230.Enable = 'off';
            app.Field_RY230.Tooltip = {'Th230/229 ratio in spike (if unknown assume infinite, Inf)'};
            app.Field_RY230.Value = Inf;
            app.Panel_AnalysisInfo = uipanel(app.ICProUIFigure);
            app.Panel_AnalysisInfo.Title = 'Analysis Information';
            app.Button_RawDataPath = uibutton(app.Panel_AnalysisInfo, 'push');
            app.Button_RawDataPath.ButtonPushedFcn = createCallbackFcn(app, @BrowseRawData, true);
            app.Button_RawDataPath.Text = '...';
            app.Field_RawDataPath = uieditfield(app.Panel_AnalysisInfo, 'text');
            app.Field_RawDataPath.ValueChangedFcn = createCallbackFcn(app, @AutoToggle, true);
            app.Field_RawDataPath.Value = 'Raw data directory';
            app.Field_SampleID = uieditfield(app.Panel_AnalysisInfo, 'text');
            app.Field_BlankID = uieditfield(app.Panel_AnalysisInfo, 'text');
            app.Label_SampleID = uilabel(app.Panel_AnalysisInfo);
            app.Label_SampleID.Text = 'Sample ID initials';
            app.Label_BlankID = uilabel(app.Panel_AnalysisInfo);
            app.Label_BlankID.Text = 'Procedural blank ID';
            app.AnalysisSelection = uibuttongroup(app.Panel_AnalysisInfo);
            app.AnalysisSelection.SelectionChangedFcn = createCallbackFcn(app, @AnalysisToggle, true);
            app.Toggle_ThU = uitogglebutton(app.AnalysisSelection);
            app.Toggle_ThU.Tag = 'ThUtoggled';
            app.Toggle_ThU.Text = 'Thorium/Uranium';
            app.Toggle_ThU.Value = true;
            app.Toggle_TE = uitogglebutton(app.AnalysisSelection);
            app.Toggle_TE.Tag = 'TEtoggled';
            app.Toggle_TE.Text = 'Trace Element';
            app.Button_InputSheet = uibutton(app.Panel_AnalysisInfo, 'push');
            app.Button_InputSheet.ButtonPushedFcn = createCallbackFcn(app, @InputSheet, true);
            app.Button_InputSheet.Text = 'Weight input table';
            app.Button_Preferences = uibutton(app.Panel_AnalysisInfo, 'push');
            app.Button_Preferences.ButtonPushedFcn = createCallbackFcn(app, @Preferences, true);
            app.Button_Preferences.Text = 'More options';
            app.Button_Run = uibutton(app.ICProUIFigure, 'push');
            app.Button_Run.ButtonPushedFcn = createCallbackFcn(app, @Execute, true);
            app.Button_Run.Text = '';
            app.Panel_TEResults = uipanel(app.ICProUIFigure);
            app.Panel_TEResults.Enable = 'off';
            app.Panel_TEResults.Title = 'Trace Element Processing Results';
            app.Panel_TEResults.Visible = 'off';
            app.TabGroup_TEResults = uitabgroup(app.Panel_TEResults);
            app.Tab_Concentrations = uitab(app.TabGroup_TEResults);
            app.Tab_Concentrations.Title = 'Concentrations';
            app.DataSelTE = uibuttongroup(app.Tab_Concentrations);
            app.DataSelTE.SelectionChangedFcn = createCallbackFcn(app, @SwitchResultTE, true);
            app.RawButton = uitogglebutton(app.DataSelTE);
            app.RawButton.Tag = 'Raw';
            app.RawButton.Enable = 'off';
            app.RawButton.Text = 'Raw data';
            app.RawButton.Value = true;
            app.RCButton = uitogglebutton(app.DataSelTE);
            app.RCButton.Tag = 'RC';
            app.RCButton.Enable = 'off';
            app.RCButton.Text = 'Rinse corrected';
            app.QCButton = uitogglebutton(app.DataSelTE);
            app.QCButton.Tag = 'QC';
            app.QCButton.Enable = 'off';
            app.QCButton.Text = 'QC corrected';
            app.SCButton = uitogglebutton(app.DataSelTE);
            app.SCButton.Tag = 'SC';
            app.SCButton.Enable = 'off';
            app.SCButton.Text = 'Spike corrected';
            app.OCButton = uitogglebutton(app.DataSelTE);
            app.OCButton.Tag = 'OC';
            app.OCButton.Enable = 'off';
            app.OCButton.Text = 'Oxide corrected';
            app.BCButton = uitogglebutton(app.DataSelTE);
            app.BCButton.Tag = 'BC';
            app.BCButton.Enable = 'off';
            app.BCButton.Text = 'Blank corrected';
            app.DCButton = uitogglebutton(app.DataSelTE);
            app.DCButton.Tag = 'DC';
            app.DCButton.Enable = 'off';
            app.DCButton.Text = 'Dilution corrected';
            app.IDButton = uitogglebutton(app.DataSelTE);
            app.IDButton.Tag = 'ID';
            app.IDButton.Enable = 'off';
            app.IDButton.Tooltip = {'Th232 and U238 concentrations determined via isotopic dilution'};
            app.IDButton.Text = 'Isotopic Dilution';
            app.CUSelTE = uibuttongroup(app.DataSelTE);
            app.CUSelTE.SelectionChangedFcn = createCallbackFcn(app, @SwitchResultTE, true);
            app.ConcTE = uiradiobutton(app.CUSelTE);
            app.ConcTE.Tag = 'Conc';
            app.ConcTE.Enable = 'off';
            app.ConcTE.Text = '  Conc. (μg/g)';
            app.ConcTE.Value = true;
            app.ErrTE = uiradiobutton(app.CUSelTE);
            app.ErrTE.Tag = 'Err';
            app.ErrTE.Enable = 'off';
            app.ErrTE.Text = '  STD (μg/g)';
            app.ErrpTE = uiradiobutton(app.CUSelTE);
            app.ErrpTE.Tag = 'Errp';
            app.ErrpTE.Enable = 'off';
            app.ErrpTE.Text = '  STD (%)';
            app.Table_TEresults = uitable(app.Tab_Concentrations);
            app.Table_TEresults.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.Table_TEresults.RowName = {};
            app.Tab_IDvsLR = uitab(app.TabGroup_TEResults);
            app.Tab_IDvsLR.Title = 'ID vs LR plots';
            app.UIDFig = uiaxes(app.Tab_IDvsLR);
            xlabel(app.UIDFig, '238U, LR (ppm)')
            ylabel(app.UIDFig, '238U, ID (ppm)')
            zlabel(app.UIDFig, 'Z')
            app.ThIDFig = uiaxes(app.Tab_IDvsLR);
            xlabel(app.ThIDFig, '232Th, LR (ppm)')
            ylabel(app.ThIDFig, '232Th, ID (ppm)')
            zlabel(app.ThIDFig, 'Z')
            app.Panel_ThUResults = uipanel(app.ICProUIFigure);
            app.Panel_ThUResults.Title = 'Thorium/Uranium Processing Results';
            app.TabGroup_ThUResults = uitabgroup(app.Panel_ThUResults);
            app.Tab_ConcRatio = uitab(app.TabGroup_ThUResults);
            app.Tab_ConcRatio.Title = 'Concentrations and Ratios';
            app.DataSelThU = uibuttongroup(app.Tab_ConcRatio);
            app.DataSelThU.SelectionChangedFcn = createCallbackFcn(app, @SwitchResultThU, true);
            app.RatioSButton = uitogglebutton(app.DataSelThU);
            app.RatioSButton.Tag = 'SampleMB';
            app.RatioSButton.Enable = 'off';
            app.RatioSButton.Text = 'Sample ratios';
            app.RatioSButton.Value = true;
            app.RatioNButton = uitogglebutton(app.DataSelThU);
            app.RatioNButton.Tag = 'NatUMB';
            app.RatioNButton.Enable = 'off';
            app.RatioNButton.Text = 'NatU ratio';
            app.ThUIDButton = uitogglebutton(app.DataSelThU);
            app.ThUIDButton.Tag = 'ThUID';
            app.ThUIDButton.Enable = 'off';
            app.ThUIDButton.Text = 'Th230 & U234 (ID)';
            app.CUSelThU = uibuttongroup(app.Tab_ConcRatio);
            app.CUSelThU.SelectionChangedFcn = createCallbackFcn(app, @SwitchResultThU, true);
            app.ConcThU = uiradiobutton(app.CUSelThU);
            app.ConcThU.Tag = 'Conc';
            app.ConcThU.Enable = 'off';
            app.ConcThU.Text = '  Conc. (μg/g)';
            app.ConcThU.Value = true;
            app.ErrThU = uiradiobutton(app.CUSelThU);
            app.ErrThU.Tag = 'Err';
            app.ErrThU.Enable = 'off';
            app.ErrThU.Text = '  STD (μg/g)';
            app.ErrpThU = uiradiobutton(app.CUSelThU);
            app.ErrpThU.Tag = 'Errp';
            app.ErrpThU.Enable = 'off';
            app.ErrpThU.Text = '  STD (%)';
            app.ThUBCcheck = uicheckbox(app.Tab_ConcRatio);
            app.ThUBCcheck.ValueChangedFcn = createCallbackFcn(app, @SwitchResultThU, true);
            app.ThUBCcheck.Enable = 'off';
            app.ThUBCcheck.Text = 'Blank correction';
            app.ThUBCcheck.Value = true;
            app.Table_ThUresults = uitable(app.Tab_ConcRatio);
            app.Table_ThUresults.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.Table_ThUresults.RowName = {};
            app.Tab_SpikeRecov = uitab(app.TabGroup_ThUResults);
            app.Tab_SpikeRecov.Title = 'Spike Recovery';
            app.RecoveryFig = uiaxes(app.Tab_SpikeRecov);
            ylabel(app.RecoveryFig, 'counts per second [cps]')
            zlabel(app.RecoveryFig, 'Z')
            app.ContextMenu_CopyThUResults = uicontextmenu(app.ICProUIFigure);
            app.CopyThU = uimenu(app.ContextMenu_CopyThUResults);
            app.CopyThU.MenuSelectedFcn = createCallbackFcn(app, @CopyResultsThU, true);
            app.CopyThU.Text = 'Copy';
            % Assign app.ContextMenu_CopyThUResults
            app.Table_ThUresults.ContextMenu = app.ContextMenu_CopyThUResults;
            app.ContextMenu_CopyTEResults = uicontextmenu(app.ICProUIFigure);
            app.CopyTE = uimenu(app.ContextMenu_CopyTEResults);
            app.CopyTE.MenuSelectedFcn = createCallbackFcn(app, @CopyResultsTE, true);
            app.CopyTE.Text = 'Copy';
            % Assign app.ContextMenu_CopyTEResults
            app.Table_TEresults.ContextMenu = app.ContextMenu_CopyTEResults;
            % Show the figure after all components are created
            app.ICProUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ICPro

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ICProUIFigure)

            % Execute the startup function
            runStartupFcn(app, @Splash)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ICProUIFigure)
        end
    end
end